classdef PipelineGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        DistancetoMonitorcmEditFieldLabel  matlab.ui.control.Label
        DistancetoMonitorcmEditField    matlab.ui.control.NumericEditField
        OSEditFieldLabel                matlab.ui.control.Label
        OSEditField                     matlab.ui.control.NumericEditField
        MouseIDEditFieldLabel           matlab.ui.control.Label
        MouseIDEditField                matlab.ui.control.EditField
        forwndows1forlinux2formacLabel  matlab.ui.control.Label
        MonitorEditFieldLabel           matlab.ui.control.Label
        MonitorEditField                matlab.ui.control.EditField
        TrialStartTTLPinEditFieldLabel  matlab.ui.control.Label
        TrialStartTTLPinEditField       matlab.ui.control.NumericEditField
        UseArduinoCheckBox              matlab.ui.control.CheckBox
        UseLabJackCheckBox              matlab.ui.control.CheckBox
        UpperLeftTTLPinEditFieldLabel   matlab.ui.control.Label
        UpperLeftTTLPinEditField        matlab.ui.control.NumericEditField
        UpperRightTTLPinEditFieldLabel  matlab.ui.control.Label
        UpperRightTTLPinEditField       matlab.ui.control.NumericEditField
        LowerLeftTTLPinEditFieldLabel   matlab.ui.control.Label
        LowerLeftTTLPinEditField        matlab.ui.control.NumericEditField
        LowerRightTTLPinEditFieldLabel  matlab.ui.control.Label
        LowerRightTTLPinEditField       matlab.ui.control.NumericEditField
        MonitorSettingsLabel            matlab.ui.control.Label
        TTLSettingsLabel                matlab.ui.control.Label
        SetupMonitorButton              matlab.ui.control.Button
        SetupTTLButton                  matlab.ui.control.Button
        FlashTab                        matlab.ui.container.Tab
        FlashDelaysEditFieldLabel       matlab.ui.control.Label
        FlashDelaysEditField            matlab.ui.control.NumericEditField
        GeneralFlashSettingsLabel       matlab.ui.control.Label
        FlashDurationsEditFieldLabel    matlab.ui.control.Label
        FlashDurationsEditField         matlab.ui.control.NumericEditField
        InitialBlackScreenCheckBox      matlab.ui.control.CheckBox
        TotalFlashesEditFieldLabel      matlab.ui.control.Label
        TotalFlashesEditField           matlab.ui.control.NumericEditField
        DifferentLocationFlashSettingsLabel  matlab.ui.control.Label
        toEditFieldLabel                matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        CenterCheckBox                  matlab.ui.control.CheckBox
        TopLeftCheckBox                 matlab.ui.control.CheckBox
        BottomLeftCheckBox              matlab.ui.control.CheckBox
        TopRightCheckBox                matlab.ui.control.CheckBox
        BottomRightCheckBox             matlab.ui.control.CheckBox
        FlashesPerLocationEditField_3Label  matlab.ui.control.Label
        FlashesPerLocationEditField_3   matlab.ui.control.NumericEditField
        DifferentLocationFlashButton    matlab.ui.control.Button
        WholeScreenFlashButton          matlab.ui.control.Button
        DriftingGratingTab              matlab.ui.container.Tab
        GeneralDriftSettingsLabel       matlab.ui.control.Label
        StimulusDelaysEditFieldLabel    matlab.ui.control.Label
        StimulusDelaysEditField         matlab.ui.control.NumericEditField
        DriftDurationsEditFieldLabel    matlab.ui.control.Label
        DriftDurationsEditField         matlab.ui.control.NumericEditField
        TotalDriftsEditFieldLabel       matlab.ui.control.Label
        TotalDriftsEditField            matlab.ui.control.NumericEditField
        toEditField_2Label              matlab.ui.control.Label
        toEditField_2                   matlab.ui.control.NumericEditField
        DifferentLocationDriftSettingsLabel  matlab.ui.control.Label
        CenterCheckBox_2                matlab.ui.control.CheckBox
        TopLeftCheckBox_2               matlab.ui.control.CheckBox
        BottomLeftCheckBox_2            matlab.ui.control.CheckBox
        TopRightCheckBox_2              matlab.ui.control.CheckBox
        BottomRightCheckBox_2           matlab.ui.control.CheckBox
        DifferentLocationDriftButton    matlab.ui.control.Button
        WholeScreenDriftButton          matlab.ui.control.Button
        DriftsPerLocationEditFieldLabel  matlab.ui.control.Label
        DriftsPerLocationEditField      matlab.ui.control.NumericEditField
        GratingSizedegEditFieldLabel    matlab.ui.control.Label
        GratingSizedegEditField         matlab.ui.control.NumericEditField
        CyclesperSecondEditFieldLabel   matlab.ui.control.Label
        CyclesperSecondEditField        matlab.ui.control.NumericEditField
        CyclesperDegreeEditFieldLabel   matlab.ui.control.Label
        CyclesperDegreeEditField        matlab.ui.control.NumericEditField
        FlipOrientationCheckBox         matlab.ui.control.CheckBox
        ColorContrastEditField_3Label   matlab.ui.control.Label
        ColorContrastEditField_3        matlab.ui.control.NumericEditField
        MaskingCheckBox                 matlab.ui.control.CheckBox
        CenterLocationDropDownLabel     matlab.ui.control.Label
        CenterLocationDropDown          matlab.ui.control.DropDown
        StaticGratingTab                matlab.ui.container.Tab
        GeneralStaticSettingsLabel      matlab.ui.control.Label
        DifferentLocationStaticSettingsLabel  matlab.ui.control.Label
        CenterCheckBox_3                matlab.ui.control.CheckBox
        TopLeftCheckBox_3               matlab.ui.control.CheckBox
        BottomLeftCheckBox_3            matlab.ui.control.CheckBox
        TopRightCheckBox_3              matlab.ui.control.CheckBox
        BottomRightCheckBox_3           matlab.ui.control.CheckBox
        FlipOrientationCheckBox_2       matlab.ui.control.CheckBox
        MaskingCheckBox_2               matlab.ui.control.CheckBox
        StimulusDelaysEditField_2Label  matlab.ui.control.Label
        StimulusDelaysEditField_2       matlab.ui.control.NumericEditField
        StaticDurationsEditFieldLabel   matlab.ui.control.Label
        StaticDurationsEditField        matlab.ui.control.NumericEditField
        TotalStaticsEditFieldLabel      matlab.ui.control.Label
        TotalStaticsEditField           matlab.ui.control.NumericEditField
        toEditField_3Label              matlab.ui.control.Label
        toEditField_3                   matlab.ui.control.NumericEditField
        StaticsPerLocationEditFieldLabel  matlab.ui.control.Label
        StaticsPerLocationEditField     matlab.ui.control.NumericEditField
        GratingSizedegEditField_2Label  matlab.ui.control.Label
        GratingSizedegEditField_2       matlab.ui.control.NumericEditField
        CyclesperSecondEditField_2Label  matlab.ui.control.Label
        CyclesperSecondEditField_2      matlab.ui.control.NumericEditField
        CyclesperDegreeEditField_2Label  matlab.ui.control.Label
        CyclesperDegreeEditField_2      matlab.ui.control.NumericEditField
        ColorContrastEditField_2Label   matlab.ui.control.Label
        ColorContrastEditField_2        matlab.ui.control.NumericEditField
        CenterLocationDropDown_2Label   matlab.ui.control.Label
        CenterLocationDropDown_2        matlab.ui.control.DropDown
        DifferentLocationStaticButton   matlab.ui.control.Button
        WholeScreenStaticButton         matlab.ui.control.Button
        ContrastReversingGratingTab     matlab.ui.container.Tab
        GeneralContrastReversingSettingsLabel  matlab.ui.control.Label
        DifferentLocationContrastReversingSettingsLabel  matlab.ui.control.Label
        CenterCheckBox_4                matlab.ui.control.CheckBox
        TopLeftCheckBox_4               matlab.ui.control.CheckBox
        BottomLeftCheckBox_4            matlab.ui.control.CheckBox
        TopRightCheckBox_4              matlab.ui.control.CheckBox
        BottomRightCheckBox_4           matlab.ui.control.CheckBox
        FlipOrientationCheckBox_3       matlab.ui.control.CheckBox
        MaskingCheckBox_3               matlab.ui.control.CheckBox
        DifferentLocationContrastReversingButton  matlab.ui.control.Button
        WholeScreenContrastReversingButton  matlab.ui.control.Button
        StimulusDelaysEditField_3Label  matlab.ui.control.Label
        StimulusDelaysEditField_3       matlab.ui.control.NumericEditField
        StimulusDurationsEditFieldLabel  matlab.ui.control.Label
        StimulusDurationsEditField      matlab.ui.control.NumericEditField
        TotalStimuliEditFieldLabel      matlab.ui.control.Label
        TotalStimuliEditField           matlab.ui.control.NumericEditField
        toEditField_4Label              matlab.ui.control.Label
        toEditField_4                   matlab.ui.control.NumericEditField
        StimuliPerLocationEditFieldLabel  matlab.ui.control.Label
        StimuliPerLocationEditField     matlab.ui.control.NumericEditField
        GratingSizedegEditField_3Label  matlab.ui.control.Label
        GratingSizedegEditField_3       matlab.ui.control.NumericEditField
        CyclesperSecondEditField_3Label  matlab.ui.control.Label
        CyclesperSecondEditField_3      matlab.ui.control.NumericEditField
        CyclesperDegreeEditField_3Label  matlab.ui.control.Label
        CyclesperDegreeEditField_3      matlab.ui.control.NumericEditField
        ColorContrastEditFieldLabel     matlab.ui.control.Label
        ColorContrastEditField          matlab.ui.control.NumericEditField
        CenterLocationDropDown_3Label   matlab.ui.control.Label
        CenterLocationDropDown_3        matlab.ui.control.DropDown
        ExitButton                      matlab.ui.control.Button
    end


    properties (Access = public)
        params % various general params for experiment
    end


    methods (Access = private)

        % Button pushed function: SetupMonitorButton
        function SetupMonitorButtonPushed(app, event)
            clear

            close all
            clc
            sca
            stimDir = '/home/adeeti/Dropbox/ProektLab_code/Adeeti_code/PsychoPhysics/stimuli/AdeetiStim/';
            cd(stimDir);
            addpath(genpath(stimDir));
            
            % Here we call some default settings for setting up Psychtoolbox
            clear params
            rng('shuffle')
            
            % Setup PTB with some default values
            PsychDefaultSetup(2);
            %PsychImaging('PrepareConfiguration');
            %PsychImaging('AddTask', 'FinalFormatting', 'DisplayColorCorrection', 'SimpleGamma');
            
            %Mouse details
            app.params.distanceToMonitor = app.DistanceToMonitorEditField.Value; %cm
            app.params.mouseID = app.MouseIDEditField.Value; 
            app.params.Date= date;
            
            % Computer details
            app.params.monitorInUse = app.MonitorEditField.Value;
            app.params.os = app.OSEditField.Value; %1 for on linux, 0 for windows, 2 for mac
            
            % TTL details
            app.params.useArduino = 0; %0 for no arduino, 1 for arduino
            app.params.useLabJack = 1;
            app.params.startTTL = 16; %number of pin for TTL for the start of a trial
            app.params.ulTTL = 17; %number of pin for TTL for the start of a grating in the upper left 
            app.params.urTTL = 18; %number of pin for TTL for the start of a grating in the upper right 
            app.params.llTTL = 19; %number of pin for TTL for the start of a grating in the lower left 
            app.params.lrTTL = 16; %number of pin for TTL for the start of a grating in the lower right 
            
            
            display('ready');
            all_screens = Screen('Screens');
            
            % If more than one monitor connected, display stimuli on all
            % non-primary monitors. If only one monitor connected, display stimuli
            % on that monitor.
            
            if max(all_screens) == 0
                screens = all_screens;
                warning('No external monitors are connected. Are you sure you want to continue?')
            else
                screens = all_screens(all_screens > 0);
            end
            screenNumber = max(screens);
            
            %save screen and screen colors
            app.params.screenNumber = screenNumber;
            app.params.white = WhiteIndex(screenNumber);
            app.params.black = BlackIndex(screenNumber);
            app.params.gray = app.params.white/2;
            [w, screenRect]=PsychImaging('OpenWindow',app.params.screenNumber, app.params.gray);
            
            app.params = configureScreens_final(w, app.params); % Set up monitors and initialize blank screen - grey background
            
            Screen('BlendFunction', w, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);
            
            AssertOpenGL
            %oldGamma =Screen('LoadNormalizedGammaTable', w, params.gammaTable');
            
            %maximize priority for good timing
            topPriorityLevel = MaxPriority(w);
            Priority(topPriorityLevel);


        end

        % Button pushed function: SetupTTLButton
        function SetupTTLButtonPushed(app, event)
            
        end

        % Button pushed function: WholeScreenFlashButton
        function WholeScreenFlashButtonPushed(app, event)
            
        end

        % Button pushed function: DifferentLocationFlashButton
        function DifferentLocationFlashButtonPushed(app, event)
            
        end

        % Button pushed function: WholeScreenDriftButton
        function WholeScreenDriftButtonPushed(app, event)
            
        end

        % Button pushed function: DifferentLocationDriftButton
        function DifferentLocationDriftButtonPushed(app, event)
            
        end

        % Button pushed function: WholeScreenStaticButton
        function WholeScreenStaticButtonPushed(app, event)
            
        end

        % Button pushed function: DifferentLocationStaticButton
        function DifferentLocationStaticButtonPushed(app, event)
            
        end

        % Button pushed function: WholeScreenContrastReversingButton
        function WholeScreenContrastReversingButtonPushed(app, event)
            
        end

        % Button pushed function: 
        % DifferentLocationContrastReversingButton
        function DifferentLocationContrastReversingButtonPushed(app, event)
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            sca;
            Priority(0);
            lj.close;
            disp("Experiment End");
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.DistancetoMonitorcmEditFieldLabel = uilabel(app.SetupTab);
            app.DistancetoMonitorcmEditFieldLabel.Text = 'Distance to Monitor (cm)';
            app.DistancetoMonitorcmEditField = uieditfield(app.SetupTab, 'numeric');
            app.DistancetoMonitorcmEditField.Limits = [0 Inf];
            app.DistancetoMonitorcmEditField.Value = 30;
            app.OSEditFieldLabel = uilabel(app.SetupTab);
            app.OSEditFieldLabel.Text = 'OS';
            app.OSEditField = uieditfield(app.SetupTab, 'numeric');
            app.MouseIDEditFieldLabel = uilabel(app.SetupTab);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.SetupTab, 'text');
            app.MouseIDEditField.Value = 'Stim Test 1';
            app.forwndows1forlinux2formacLabel = uilabel(app.SetupTab);
            app.forwndows1forlinux2formacLabel.Text = '0 for wndows, 1 for linux, 2 for mac';
            app.MonitorEditFieldLabel = uilabel(app.SetupTab);
            app.MonitorEditFieldLabel.Text = 'Monitor';
            app.MonitorEditField = uieditfield(app.SetupTab, 'text');
            app.MonitorEditField.Value = 'testing';
            app.TrialStartTTLPinEditFieldLabel = uilabel(app.SetupTab);
            app.TrialStartTTLPinEditFieldLabel.Text = 'Trial Start TTL Pin';
            app.TrialStartTTLPinEditField = uieditfield(app.SetupTab, 'numeric');
            app.TrialStartTTLPinEditField.Limits = [0 Inf];
            app.TrialStartTTLPinEditField.Value = 16;
            app.UseArduinoCheckBox = uicheckbox(app.SetupTab);
            app.UseArduinoCheckBox.Text = 'Use Arduino?';
            app.UseLabJackCheckBox = uicheckbox(app.SetupTab);
            app.UseLabJackCheckBox.Text = 'Use LabJack?';
            app.UseLabJackCheckBox.Value = true;
            app.UpperLeftTTLPinEditFieldLabel = uilabel(app.SetupTab);
            app.UpperLeftTTLPinEditFieldLabel.Text = 'Upper Left TTL Pin';
            app.UpperLeftTTLPinEditField = uieditfield(app.SetupTab, 'numeric');
            app.UpperLeftTTLPinEditField.Limits = [0 Inf];
            app.UpperLeftTTLPinEditField.Value = 17;
            app.UpperRightTTLPinEditFieldLabel = uilabel(app.SetupTab);
            app.UpperRightTTLPinEditFieldLabel.Text = 'Upper Right TTL Pin';
            app.UpperRightTTLPinEditField = uieditfield(app.SetupTab, 'numeric');
            app.UpperRightTTLPinEditField.Limits = [0 Inf];
            app.UpperRightTTLPinEditField.Value = 18;
            app.LowerLeftTTLPinEditFieldLabel = uilabel(app.SetupTab);
            app.LowerLeftTTLPinEditFieldLabel.Text = 'Lower Left TTL Pin';
            app.LowerLeftTTLPinEditField = uieditfield(app.SetupTab, 'numeric');
            app.LowerLeftTTLPinEditField.Limits = [0 Inf];
            app.LowerLeftTTLPinEditField.Value = 19;
            app.LowerRightTTLPinEditFieldLabel = uilabel(app.SetupTab);
            app.LowerRightTTLPinEditFieldLabel.Text = 'Lower Right TTL Pin';
            app.LowerRightTTLPinEditField = uieditfield(app.SetupTab, 'numeric');
            app.LowerRightTTLPinEditField.Limits = [0 Inf];
            app.LowerRightTTLPinEditField.Value = 16;
            app.MonitorSettingsLabel = uilabel(app.SetupTab);
            app.MonitorSettingsLabel.Text = 'Monitor Settings';
            app.TTLSettingsLabel = uilabel(app.SetupTab);
            app.TTLSettingsLabel.Text = 'TTL Settings';
            app.SetupMonitorButton = uibutton(app.SetupTab, 'push');
            app.SetupMonitorButton.ButtonPushedFcn = createCallbackFcn(app, @SetupMonitorButtonPushed, true);
            app.SetupMonitorButton.Text = 'Set up Monitor';
            app.SetupTTLButton = uibutton(app.SetupTab, 'push');
            app.SetupTTLButton.ButtonPushedFcn = createCallbackFcn(app, @SetupTTLButtonPushed, true);
            app.SetupTTLButton.Text = 'Set up TTL';
            app.FlashTab = uitab(app.TabGroup);
            app.FlashTab.Title = 'Flash';
            app.FlashDelaysEditFieldLabel = uilabel(app.FlashTab);
            app.FlashDelaysEditFieldLabel.Text = 'Flash Delay (s)';
            app.FlashDelaysEditField = uieditfield(app.FlashTab, 'numeric');
            app.FlashDelaysEditField.Limits = [0 Inf];
            app.FlashDelaysEditField.Value = 3;
            app.GeneralFlashSettingsLabel = uilabel(app.FlashTab);
            app.GeneralFlashSettingsLabel.Text = 'General Flash Settings';
            app.FlashDurationsEditFieldLabel = uilabel(app.FlashTab);
            app.FlashDurationsEditFieldLabel.Text = 'Flash Duration (s)';
            app.FlashDurationsEditField = uieditfield(app.FlashTab, 'numeric');
            app.FlashDurationsEditField.Limits = [0 Inf];
            app.FlashDurationsEditField.Value = 0.1;
            app.InitialBlackScreenCheckBox = uicheckbox(app.FlashTab);
            app.InitialBlackScreenCheckBox.Text = 'Initial Black Screen';
            app.InitialBlackScreenCheckBox.Value = true;
            app.TotalFlashesEditFieldLabel = uilabel(app.FlashTab);
            app.TotalFlashesEditFieldLabel.Text = 'Total Flashes';
            app.TotalFlashesEditField = uieditfield(app.FlashTab, 'numeric');
            app.TotalFlashesEditField.Limits = [0 Inf];
            app.TotalFlashesEditField.Value = 100;
            app.DifferentLocationFlashSettingsLabel = uilabel(app.FlashTab);
            app.DifferentLocationFlashSettingsLabel.Text = 'Different Location Flash Settings';
            app.toEditFieldLabel = uilabel(app.FlashTab);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.FlashTab, 'numeric');
            app.toEditField.Limits = [0 Inf];
            app.toEditField.Value = 5;
            app.CenterCheckBox = uicheckbox(app.FlashTab);
            app.CenterCheckBox.Text = 'Center';
            app.TopLeftCheckBox = uicheckbox(app.FlashTab);
            app.TopLeftCheckBox.Text = 'Top Left';
            app.TopLeftCheckBox.Value = true;
            app.BottomLeftCheckBox = uicheckbox(app.FlashTab);
            app.BottomLeftCheckBox.Text = 'Bottom Left';
            app.BottomLeftCheckBox.Value = true;
            app.TopRightCheckBox = uicheckbox(app.FlashTab);
            app.TopRightCheckBox.Text = 'Top Right';
            app.TopRightCheckBox.Value = true;
            app.BottomRightCheckBox = uicheckbox(app.FlashTab);
            app.BottomRightCheckBox.Text = 'Bottom Right';
            app.BottomRightCheckBox.Value = true;
            app.FlashesPerLocationEditField_3Label = uilabel(app.FlashTab);
            app.FlashesPerLocationEditField_3Label.Text = 'Flashes Per Location';
            app.FlashesPerLocationEditField_3 = uieditfield(app.FlashTab, 'numeric');
            app.FlashesPerLocationEditField_3.Limits = [0 Inf];
            app.FlashesPerLocationEditField_3.Value = 50;
            app.DifferentLocationFlashButton = uibutton(app.FlashTab, 'push');
            app.DifferentLocationFlashButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentLocationFlashButtonPushed, true);
            app.DifferentLocationFlashButton.Text = 'Different Location Flash';
            app.WholeScreenFlashButton = uibutton(app.FlashTab, 'push');
            app.WholeScreenFlashButton.ButtonPushedFcn = createCallbackFcn(app, @WholeScreenFlashButtonPushed, true);
            app.WholeScreenFlashButton.Text = 'Whole Screen Flash';
            app.DriftingGratingTab = uitab(app.TabGroup);
            app.DriftingGratingTab.Title = 'Drifting Grating';
            app.GeneralDriftSettingsLabel = uilabel(app.DriftingGratingTab);
            app.GeneralDriftSettingsLabel.Text = 'General Drift Settings';
            app.StimulusDelaysEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.StimulusDelaysEditFieldLabel.Text = 'Stimulus Delay (s)';
            app.StimulusDelaysEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.StimulusDelaysEditField.Limits = [0 Inf];
            app.StimulusDelaysEditField.Value = 3;
            app.DriftDurationsEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.DriftDurationsEditFieldLabel.Text = 'Drift Duration (s)';
            app.DriftDurationsEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.DriftDurationsEditField.Limits = [0 Inf];
            app.DriftDurationsEditField.Value = 1;
            app.TotalDriftsEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.TotalDriftsEditFieldLabel.Text = 'Total Drifts';
            app.TotalDriftsEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.TotalDriftsEditField.Limits = [0 Inf];
            app.TotalDriftsEditField.Value = 100;
            app.toEditField_2Label = uilabel(app.DriftingGratingTab);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.DriftingGratingTab, 'numeric');
            app.toEditField_2.Limits = [0 Inf];
            app.toEditField_2.Value = 4;
            app.DifferentLocationDriftSettingsLabel = uilabel(app.DriftingGratingTab);
            app.DifferentLocationDriftSettingsLabel.Text = 'Different Location Drift Settings';
            app.CenterCheckBox_2 = uicheckbox(app.DriftingGratingTab);
            app.CenterCheckBox_2.Text = 'Center';
            app.TopLeftCheckBox_2 = uicheckbox(app.DriftingGratingTab);
            app.TopLeftCheckBox_2.Text = 'Top Left';
            app.TopLeftCheckBox_2.Value = true;
            app.BottomLeftCheckBox_2 = uicheckbox(app.DriftingGratingTab);
            app.BottomLeftCheckBox_2.Text = 'Bottom Left';
            app.BottomLeftCheckBox_2.Value = true;
            app.TopRightCheckBox_2 = uicheckbox(app.DriftingGratingTab);
            app.TopRightCheckBox_2.Text = 'Top Right';
            app.TopRightCheckBox_2.Value = true;
            app.BottomRightCheckBox_2 = uicheckbox(app.DriftingGratingTab);
            app.BottomRightCheckBox_2.Text = 'Bottom Right';
            app.BottomRightCheckBox_2.Value = true;
            app.DifferentLocationDriftButton = uibutton(app.DriftingGratingTab, 'push');
            app.DifferentLocationDriftButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentLocationDriftButtonPushed, true);
            app.DifferentLocationDriftButton.Text = 'Different Location Drift';
            app.WholeScreenDriftButton = uibutton(app.DriftingGratingTab, 'push');
            app.WholeScreenDriftButton.ButtonPushedFcn = createCallbackFcn(app, @WholeScreenDriftButtonPushed, true);
            app.WholeScreenDriftButton.Text = 'Whole Screen Drift';
            app.DriftsPerLocationEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.DriftsPerLocationEditFieldLabel.Text = 'Drifts Per Location';
            app.DriftsPerLocationEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.DriftsPerLocationEditField.Limits = [0 Inf];
            app.DriftsPerLocationEditField.Value = 50;
            app.GratingSizedegEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.GratingSizedegEditFieldLabel.Text = 'Grating Size (deg)';
            app.GratingSizedegEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.GratingSizedegEditField.Limits = [0 Inf];
            app.GratingSizedegEditField.Value = 70;
            app.CyclesperSecondEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.CyclesperSecondEditFieldLabel.Text = 'Cycles per Second';
            app.CyclesperSecondEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.CyclesperSecondEditField.Limits = [0 Inf];
            app.CyclesperSecondEditField.Value = 3;
            app.CyclesperDegreeEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.CyclesperDegreeEditFieldLabel.Text = 'Cycles per Degree';
            app.CyclesperDegreeEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.CyclesperDegreeEditField.Limits = [0 Inf];
            app.CyclesperDegreeEditField.Value = 0.09;
            app.FlipOrientationCheckBox = uicheckbox(app.DriftingGratingTab);
            app.FlipOrientationCheckBox.Text = 'Flip Orientation?';
            app.ColorContrastEditField_3Label = uilabel(app.DriftingGratingTab);
            app.ColorContrastEditField_3Label.Text = 'Color Contrast';
            app.ColorContrastEditField_3 = uieditfield(app.DriftingGratingTab, 'numeric');
            app.ColorContrastEditField_3.Limits = [0 1];
            app.ColorContrastEditField_3.Value = 1;
            app.MaskingCheckBox = uicheckbox(app.DriftingGratingTab);
            app.MaskingCheckBox.Text = 'Masking';
            app.MaskingCheckBox.Value = true;
            app.CenterLocationDropDownLabel = uilabel(app.DriftingGratingTab);
            app.CenterLocationDropDownLabel.Text = 'Center Location';
            app.CenterLocationDropDown = uidropdown(app.DriftingGratingTab);
            app.CenterLocationDropDown.Items = {'Center', 'Top Left', 'Top Right', 'Bottom Left', 'Bottom Right'};
            app.CenterLocationDropDown.Value = 'Center';
            app.StaticGratingTab = uitab(app.TabGroup);
            app.StaticGratingTab.Title = 'Static Grating';
            app.GeneralStaticSettingsLabel = uilabel(app.StaticGratingTab);
            app.GeneralStaticSettingsLabel.Text = 'General Static Settings';
            app.DifferentLocationStaticSettingsLabel = uilabel(app.StaticGratingTab);
            app.DifferentLocationStaticSettingsLabel.Text = 'Different Location Static Settings';
            app.CenterCheckBox_3 = uicheckbox(app.StaticGratingTab);
            app.CenterCheckBox_3.Text = 'Center';
            app.TopLeftCheckBox_3 = uicheckbox(app.StaticGratingTab);
            app.TopLeftCheckBox_3.Text = 'Top Left';
            app.TopLeftCheckBox_3.Value = true;
            app.BottomLeftCheckBox_3 = uicheckbox(app.StaticGratingTab);
            app.BottomLeftCheckBox_3.Text = 'Bottom Left';
            app.BottomLeftCheckBox_3.Value = true;
            app.TopRightCheckBox_3 = uicheckbox(app.StaticGratingTab);
            app.TopRightCheckBox_3.Text = 'Top Right';
            app.TopRightCheckBox_3.Value = true;
            app.BottomRightCheckBox_3 = uicheckbox(app.StaticGratingTab);
            app.BottomRightCheckBox_3.Text = 'Bottom Right';
            app.BottomRightCheckBox_3.Value = true;
            app.FlipOrientationCheckBox_2 = uicheckbox(app.StaticGratingTab);
            app.FlipOrientationCheckBox_2.Text = 'Flip Orientation?';
            app.MaskingCheckBox_2 = uicheckbox(app.StaticGratingTab);
            app.MaskingCheckBox_2.Text = 'Masking';
            app.MaskingCheckBox_2.Value = true;
            app.StimulusDelaysEditField_2Label = uilabel(app.StaticGratingTab);
            app.StimulusDelaysEditField_2Label.Text = 'Stimulus Delay (s)';
            app.StimulusDelaysEditField_2 = uieditfield(app.StaticGratingTab, 'numeric');
            app.StimulusDelaysEditField_2.Limits = [0 Inf];
            app.StimulusDelaysEditField_2.Value = 3;
            app.StaticDurationsEditFieldLabel = uilabel(app.StaticGratingTab);
            app.StaticDurationsEditFieldLabel.Text = 'Static Duration (s)';
            app.StaticDurationsEditField = uieditfield(app.StaticGratingTab, 'numeric');
            app.StaticDurationsEditField.Limits = [0 Inf];
            app.StaticDurationsEditField.Value = 1;
            app.TotalStaticsEditFieldLabel = uilabel(app.StaticGratingTab);
            app.TotalStaticsEditFieldLabel.Text = 'Total Statics';
            app.TotalStaticsEditField = uieditfield(app.StaticGratingTab, 'numeric');
            app.TotalStaticsEditField.Limits = [0 Inf];
            app.TotalStaticsEditField.Value = 100;
            app.toEditField_3Label = uilabel(app.StaticGratingTab);
            app.toEditField_3Label.Text = 'to';
            app.toEditField_3 = uieditfield(app.StaticGratingTab, 'numeric');
            app.toEditField_3.Limits = [0 Inf];
            app.toEditField_3.Value = 4;
            app.StaticsPerLocationEditFieldLabel = uilabel(app.StaticGratingTab);
            app.StaticsPerLocationEditFieldLabel.Text = 'Statics Per Location';
            app.StaticsPerLocationEditField = uieditfield(app.StaticGratingTab, 'numeric');
            app.StaticsPerLocationEditField.Limits = [0 Inf];
            app.StaticsPerLocationEditField.Value = 50;
            app.GratingSizedegEditField_2Label = uilabel(app.StaticGratingTab);
            app.GratingSizedegEditField_2Label.Text = 'Grating Size (deg)';
            app.GratingSizedegEditField_2 = uieditfield(app.StaticGratingTab, 'numeric');
            app.GratingSizedegEditField_2.Limits = [0 Inf];
            app.GratingSizedegEditField_2.Value = 70;
            app.CyclesperSecondEditField_2Label = uilabel(app.StaticGratingTab);
            app.CyclesperSecondEditField_2Label.Text = 'Cycles per Second';
            app.CyclesperSecondEditField_2 = uieditfield(app.StaticGratingTab, 'numeric');
            app.CyclesperSecondEditField_2.Limits = [0 Inf];
            app.CyclesperDegreeEditField_2Label = uilabel(app.StaticGratingTab);
            app.CyclesperDegreeEditField_2Label.Text = 'Cycles per Degree';
            app.CyclesperDegreeEditField_2 = uieditfield(app.StaticGratingTab, 'numeric');
            app.CyclesperDegreeEditField_2.Limits = [0 Inf];
            app.CyclesperDegreeEditField_2.Value = 0.09;
            app.ColorContrastEditField_2Label = uilabel(app.StaticGratingTab);
            app.ColorContrastEditField_2Label.Text = 'Color Contrast';
            app.ColorContrastEditField_2 = uieditfield(app.StaticGratingTab, 'numeric');
            app.ColorContrastEditField_2.Limits = [0 1];
            app.ColorContrastEditField_2.Value = 1;
            app.CenterLocationDropDown_2Label = uilabel(app.StaticGratingTab);
            app.CenterLocationDropDown_2Label.Text = 'Center Location';
            app.CenterLocationDropDown_2 = uidropdown(app.StaticGratingTab);
            app.CenterLocationDropDown_2.Items = {'Center', 'Top Left', 'Top Right', 'Bottom Left', 'Bottom Right'};
            app.CenterLocationDropDown_2.Value = 'Center';
            app.DifferentLocationStaticButton = uibutton(app.StaticGratingTab, 'push');
            app.DifferentLocationStaticButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentLocationStaticButtonPushed, true);
            app.DifferentLocationStaticButton.Text = 'Different Location Static';
            app.WholeScreenStaticButton = uibutton(app.StaticGratingTab, 'push');
            app.WholeScreenStaticButton.ButtonPushedFcn = createCallbackFcn(app, @WholeScreenStaticButtonPushed, true);
            app.WholeScreenStaticButton.Text = 'Whole Screen Static';
            app.ContrastReversingGratingTab = uitab(app.TabGroup);
            app.ContrastReversingGratingTab.Title = 'Contrast Reversing Grating';
            app.GeneralContrastReversingSettingsLabel = uilabel(app.ContrastReversingGratingTab);
            app.GeneralContrastReversingSettingsLabel.Text = 'General Contrast Reversing Settings';
            app.DifferentLocationContrastReversingSettingsLabel = uilabel(app.ContrastReversingGratingTab);
            app.DifferentLocationContrastReversingSettingsLabel.Text = 'Different Location Contrast Reversing Settings';
            app.CenterCheckBox_4 = uicheckbox(app.ContrastReversingGratingTab);
            app.CenterCheckBox_4.Text = 'Center';
            app.TopLeftCheckBox_4 = uicheckbox(app.ContrastReversingGratingTab);
            app.TopLeftCheckBox_4.Text = 'Top Left';
            app.TopLeftCheckBox_4.Value = true;
            app.BottomLeftCheckBox_4 = uicheckbox(app.ContrastReversingGratingTab);
            app.BottomLeftCheckBox_4.Text = 'Bottom Left';
            app.BottomLeftCheckBox_4.Value = true;
            app.TopRightCheckBox_4 = uicheckbox(app.ContrastReversingGratingTab);
            app.TopRightCheckBox_4.Text = 'Top Right';
            app.TopRightCheckBox_4.Value = true;
            app.BottomRightCheckBox_4 = uicheckbox(app.ContrastReversingGratingTab);
            app.BottomRightCheckBox_4.Text = 'Bottom Right';
            app.BottomRightCheckBox_4.Value = true;
            app.FlipOrientationCheckBox_3 = uicheckbox(app.ContrastReversingGratingTab);
            app.FlipOrientationCheckBox_3.Text = 'Flip Orientation?';
            app.MaskingCheckBox_3 = uicheckbox(app.ContrastReversingGratingTab);
            app.MaskingCheckBox_3.Text = 'Masking';
            app.MaskingCheckBox_3.Value = true;
            app.DifferentLocationContrastReversingButton = uibutton(app.ContrastReversingGratingTab, 'push');
            app.DifferentLocationContrastReversingButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentLocationContrastReversingButtonPushed, true);
            app.DifferentLocationContrastReversingButton.Text = 'Different Location Contrast Reversing';
            app.WholeScreenContrastReversingButton = uibutton(app.ContrastReversingGratingTab, 'push');
            app.WholeScreenContrastReversingButton.ButtonPushedFcn = createCallbackFcn(app, @WholeScreenContrastReversingButtonPushed, true);
            app.WholeScreenContrastReversingButton.Text = 'Whole Screen Contrast Reversing';
            app.StimulusDelaysEditField_3Label = uilabel(app.ContrastReversingGratingTab);
            app.StimulusDelaysEditField_3Label.Text = 'Stimulus Delay (s)';
            app.StimulusDelaysEditField_3 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.StimulusDelaysEditField_3.Limits = [0 Inf];
            app.StimulusDelaysEditField_3.Value = 3;
            app.StimulusDurationsEditFieldLabel = uilabel(app.ContrastReversingGratingTab);
            app.StimulusDurationsEditFieldLabel.Text = 'Stimulus Duration (s)';
            app.StimulusDurationsEditField = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.StimulusDurationsEditField.Limits = [0 Inf];
            app.StimulusDurationsEditField.Value = 1;
            app.TotalStimuliEditFieldLabel = uilabel(app.ContrastReversingGratingTab);
            app.TotalStimuliEditFieldLabel.Text = 'Total Stimuli';
            app.TotalStimuliEditField = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.TotalStimuliEditField.Limits = [0 Inf];
            app.TotalStimuliEditField.Value = 100;
            app.toEditField_4Label = uilabel(app.ContrastReversingGratingTab);
            app.toEditField_4Label.Text = 'to';
            app.toEditField_4 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.toEditField_4.Limits = [0 Inf];
            app.toEditField_4.Value = 4;
            app.StimuliPerLocationEditFieldLabel = uilabel(app.ContrastReversingGratingTab);
            app.StimuliPerLocationEditFieldLabel.Text = 'Stimuli Per Location';
            app.StimuliPerLocationEditField = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.StimuliPerLocationEditField.Limits = [0 Inf];
            app.StimuliPerLocationEditField.Value = 50;
            app.GratingSizedegEditField_3Label = uilabel(app.ContrastReversingGratingTab);
            app.GratingSizedegEditField_3Label.Text = 'Grating Size (deg)';
            app.GratingSizedegEditField_3 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.GratingSizedegEditField_3.Limits = [0 Inf];
            app.GratingSizedegEditField_3.Value = 70;
            app.CyclesperSecondEditField_3Label = uilabel(app.ContrastReversingGratingTab);
            app.CyclesperSecondEditField_3Label.Text = 'Cycles per Second';
            app.CyclesperSecondEditField_3 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.CyclesperSecondEditField_3.Limits = [0 Inf];
            app.CyclesperSecondEditField_3.Value = 3;
            app.CyclesperDegreeEditField_3Label = uilabel(app.ContrastReversingGratingTab);
            app.CyclesperDegreeEditField_3Label.Text = 'Cycles per Degree';
            app.CyclesperDegreeEditField_3 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.CyclesperDegreeEditField_3.Limits = [0 Inf];
            app.CyclesperDegreeEditField_3.Value = 0.09;
            app.ColorContrastEditFieldLabel = uilabel(app.ContrastReversingGratingTab);
            app.ColorContrastEditFieldLabel.Text = 'Color Contrast';
            app.ColorContrastEditField = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.ColorContrastEditField.Limits = [0 1];
            app.ColorContrastEditField.Value = 1;
            app.CenterLocationDropDown_3Label = uilabel(app.ContrastReversingGratingTab);
            app.CenterLocationDropDown_3Label.Text = 'Center Location';
            app.CenterLocationDropDown_3 = uidropdown(app.ContrastReversingGratingTab);
            app.CenterLocationDropDown_3.Items = {'Center', 'Top Left', 'Top Right', 'Bottom Left', 'Bottom Right'};
            app.CenterLocationDropDown_3.Value = 'Center';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PipelineGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end