classdef PipelineGUI112219 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        DistancetoMonitorcmEditFieldLabel  matlab.ui.control.Label
        DistancetoMonitorcmEditField    matlab.ui.control.NumericEditField
        OSEditFieldLabel                matlab.ui.control.Label
        OSEditField                     matlab.ui.control.NumericEditField
        MouseIDEditFieldLabel           matlab.ui.control.Label
        MouseIDEditField                matlab.ui.control.EditField
        forwndows1forlinux2formacLabel  matlab.ui.control.Label
        MonitorEditFieldLabel           matlab.ui.control.Label
        MonitorEditField                matlab.ui.control.EditField
        TrialStartTTLPinEditFieldLabel  matlab.ui.control.Label
        TrialStartTTLPinEditField       matlab.ui.control.NumericEditField
        UseArduinoCheckBox              matlab.ui.control.CheckBox
        UseLabJackCheckBox              matlab.ui.control.CheckBox
        UpperLeftTTLPinEditFieldLabel   matlab.ui.control.Label
        UpperLeftTTLPinEditField        matlab.ui.control.NumericEditField
        UpperRightTTLPinEditFieldLabel  matlab.ui.control.Label
        UpperRightTTLPinEditField       matlab.ui.control.NumericEditField
        LowerLeftTTLPinEditFieldLabel   matlab.ui.control.Label
        LowerLeftTTLPinEditField        matlab.ui.control.NumericEditField
        LowerRightTTLPinEditFieldLabel  matlab.ui.control.Label
        LowerRightTTLPinEditField       matlab.ui.control.NumericEditField
        MonitorSettingsLabel            matlab.ui.control.Label
        TTLSettingsLabel                matlab.ui.control.Label
        SetupMonitorButton              matlab.ui.control.Button
        PrintTrialstotxtFileCheckBox    matlab.ui.control.CheckBox
        PrintTrialstomatFileCheckBox    matlab.ui.control.CheckBox
        CenterTTLPinEditFieldLabel      matlab.ui.control.Label
        CenterTTLPinEditField           matlab.ui.control.NumericEditField
        FlashTab                        matlab.ui.container.Tab
        FlashDelaysEditFieldLabel       matlab.ui.control.Label
        FlashDelaysEditField            matlab.ui.control.NumericEditField
        GeneralFlashSettingsLabel       matlab.ui.control.Label
        FlashDurationsEditFieldLabel    matlab.ui.control.Label
        FlashDurationsEditField         matlab.ui.control.NumericEditField
        InitialBlackScreenCheckBox      matlab.ui.control.CheckBox
        TotalBigFlashesEditFieldLabel   matlab.ui.control.Label
        TotalFlashesEditField           matlab.ui.control.NumericEditField
        DifferentLocationFlashSettingsLabel  matlab.ui.control.Label
        toEditFieldLabel                matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        CenterCheckBox                  matlab.ui.control.CheckBox
        TopLeftCheckBox                 matlab.ui.control.CheckBox
        BottomLeftCheckBox              matlab.ui.control.CheckBox
        TopRightCheckBox                matlab.ui.control.CheckBox
        BottomRightCheckBox             matlab.ui.control.CheckBox
        FlashesPerLocationEditField_3Label  matlab.ui.control.Label
        FlashesPerLocationEditField_3   matlab.ui.control.NumericEditField
        DifferentLocationFlashButton    matlab.ui.control.Button
        WholeScreenFlashButton          matlab.ui.control.Button
        OutputFileNameEditFieldLabel    matlab.ui.control.Label
        OutputFileNameEditField         matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        AddCustomButton                 matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        RemoveCustomButton              matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
        DriftingGratingTab              matlab.ui.container.Tab
        GeneralDriftSettingsLabel       matlab.ui.control.Label
        StimulusDelaysEditFieldLabel    matlab.ui.control.Label
        StimulusDelaysEditField         matlab.ui.control.NumericEditField
        DriftDurationsEditFieldLabel    matlab.ui.control.Label
        DriftDurationsEditField         matlab.ui.control.NumericEditField
        TotalBigDriftsEditFieldLabel    matlab.ui.control.Label
        TotalDriftsEditField            matlab.ui.control.NumericEditField
        toEditField_2Label              matlab.ui.control.Label
        toEditField_2                   matlab.ui.control.NumericEditField
        DifferentLocationDriftSettingsLabel  matlab.ui.control.Label
        CenterCheckBox_2                matlab.ui.control.CheckBox
        TopLeftCheckBox_2               matlab.ui.control.CheckBox
        BottomLeftCheckBox_2            matlab.ui.control.CheckBox
        TopRightCheckBox_2              matlab.ui.control.CheckBox
        BottomRightCheckBox_2           matlab.ui.control.CheckBox
        DifferentLocationDriftButton    matlab.ui.control.Button
        WholeScreenDriftButton          matlab.ui.control.Button
        DriftsPerLocationEditFieldLabel  matlab.ui.control.Label
        DriftsPerLocationEditField      matlab.ui.control.NumericEditField
        GratingSizedegEditFieldLabel    matlab.ui.control.Label
        GratingSizedegEditField         matlab.ui.control.NumericEditField
        CyclesperSecondEditFieldLabel   matlab.ui.control.Label
        CyclesperSecondEditField        matlab.ui.control.NumericEditField
        CyclesperDegreeEditFieldLabel   matlab.ui.control.Label
        CyclesperDegreeEditField        matlab.ui.control.NumericEditField
        ColorContrastEditField_3Label   matlab.ui.control.Label
        ColorContrastEditField_3        matlab.ui.control.NumericEditField
        MaskingCheckBox                 matlab.ui.control.CheckBox
        CenterLocationDropDownLabel     matlab.ui.control.Label
        CenterLocationDropDown          matlab.ui.control.DropDown
        OutputFileNameEditField_2Label  matlab.ui.control.Label
        OutputFileNameEditField_2       matlab.ui.control.EditField
        AngleEditFieldLabel             matlab.ui.control.Label
        AngleEditField                  matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        TextArea_2Label                 matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        AddCustomButton_2               matlab.ui.control.Button
        RemoveCustomButton_2            matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        StaticGratingTab                matlab.ui.container.Tab
        GeneralStaticSettingsLabel      matlab.ui.control.Label
        DifferentLocationStaticSettingsLabel  matlab.ui.control.Label
        CenterCheckBox_3                matlab.ui.control.CheckBox
        TopLeftCheckBox_3               matlab.ui.control.CheckBox
        BottomLeftCheckBox_3            matlab.ui.control.CheckBox
        TopRightCheckBox_3              matlab.ui.control.CheckBox
        BottomRightCheckBox_3           matlab.ui.control.CheckBox
        MaskingCheckBox_2               matlab.ui.control.CheckBox
        StimulusDelaysEditField_2Label  matlab.ui.control.Label
        StimulusDelaysEditField_2       matlab.ui.control.NumericEditField
        StaticDurationsEditFieldLabel   matlab.ui.control.Label
        StaticDurationsEditField        matlab.ui.control.NumericEditField
        TotalBigStaticsEditFieldLabel   matlab.ui.control.Label
        TotalStaticsEditField           matlab.ui.control.NumericEditField
        toEditField_3Label              matlab.ui.control.Label
        toEditField_3                   matlab.ui.control.NumericEditField
        StaticsPerLocationEditFieldLabel  matlab.ui.control.Label
        StaticsPerLocationEditField     matlab.ui.control.NumericEditField
        GratingSizedegEditField_2Label  matlab.ui.control.Label
        GratingSizedegEditField_2       matlab.ui.control.NumericEditField
        CyclesperSecondEditField_2Label  matlab.ui.control.Label
        CyclesperSecondEditField_2      matlab.ui.control.NumericEditField
        CyclesperDegreeEditField_2Label  matlab.ui.control.Label
        CyclesperDegreeEditField_2      matlab.ui.control.NumericEditField
        ColorContrastEditField_2Label   matlab.ui.control.Label
        ColorContrastEditField_2        matlab.ui.control.NumericEditField
        CenterLocationDropDown_2Label   matlab.ui.control.Label
        CenterLocationDropDown_2        matlab.ui.control.DropDown
        DifferentLocationStaticButton   matlab.ui.control.Button
        WholeScreenStaticButton         matlab.ui.control.Button
        OutputFileNameEditField_3Label  matlab.ui.control.Label
        OutputFileNameEditField_3       matlab.ui.control.EditField
        AngleEditField_2Label           matlab.ui.control.Label
        AngleEditField_2                matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_6Label                matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        TextArea_3Label                 matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        AddCustomButton_3               matlab.ui.control.Button
        RemoveCustomButton_3            matlab.ui.control.Button
        ContrastReversingGratingTab     matlab.ui.container.Tab
        GeneralContrastReversingSettingsLabel  matlab.ui.control.Label
        DifferentLocationContrastReversingSettingsLabel  matlab.ui.control.Label
        CenterCheckBox_4                matlab.ui.control.CheckBox
        TopLeftCheckBox_4               matlab.ui.control.CheckBox
        BottomLeftCheckBox_4            matlab.ui.control.CheckBox
        TopRightCheckBox_4              matlab.ui.control.CheckBox
        BottomRightCheckBox_4           matlab.ui.control.CheckBox
        MaskingCheckBox_3               matlab.ui.control.CheckBox
        DifferentLocationContrastReversingButton  matlab.ui.control.Button
        WholeScreenContrastReversingButton  matlab.ui.control.Button
        StimulusDelaysEditField_3Label  matlab.ui.control.Label
        StimulusDelaysEditField_3       matlab.ui.control.NumericEditField
        StimulusDurationsEditFieldLabel  matlab.ui.control.Label
        StimulusDurationsEditField      matlab.ui.control.NumericEditField
        TotalBigStimuliEditFieldLabel   matlab.ui.control.Label
        TotalStimuliEditField           matlab.ui.control.NumericEditField
        toEditField_4Label              matlab.ui.control.Label
        toEditField_4                   matlab.ui.control.NumericEditField
        StimuliPerLocationEditFieldLabel  matlab.ui.control.Label
        StimuliPerLocationEditField     matlab.ui.control.NumericEditField
        GratingSizedegEditField_3Label  matlab.ui.control.Label
        GratingSizedegEditField_3       matlab.ui.control.NumericEditField
        CyclesperSecondEditField_3Label  matlab.ui.control.Label
        CyclesperSecondEditField_3      matlab.ui.control.NumericEditField
        CyclesperDegreeEditField_3Label  matlab.ui.control.Label
        CyclesperDegreeEditField_3      matlab.ui.control.NumericEditField
        ColorContrastEditFieldLabel     matlab.ui.control.Label
        ColorContrastEditField          matlab.ui.control.NumericEditField
        CenterLocationDropDown_3Label   matlab.ui.control.Label
        CenterLocationDropDown_3        matlab.ui.control.DropDown
        OutputFileNameEditField_4Label  matlab.ui.control.Label
        OutputFileNameEditField_4       matlab.ui.control.EditField
        AngleEditField_3Label           matlab.ui.control.Label
        AngleEditField_3                matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_8Label                matlab.ui.control.Label
        EditField_8                     matlab.ui.control.NumericEditField
        TextArea_4Label                 matlab.ui.control.Label
        TextArea_4                      matlab.ui.control.TextArea
        AddCustomButton_4               matlab.ui.control.Button
        RemoveCustomButton_4            matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        StopLabel                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Gauge                           matlab.ui.control.LinearGauge
    end


    properties (Access = public)
        params % various general params for experiment
        fParam %params for flashes
        swParam %params for corner flashes
        bDParam %params for drifting (and static gratings)
        cRParam %params for contrast reversing gratings
    end

    methods (Access = private)
    
        function UpdateTextAreas(app)
            %this function updates all the text areas that display custom coords
            textVal = 'Current Custom Values:';
            if size(app.params.customLocs, 1) > 0
                for i = 1:size(app.params.customLocs, 1)
                    textVal = horzcat(textVal, newline, '(', num2str(app.params.customLocs(i, 1)), ', ',...
                        num2str(app.params.customLocs(i, 2)), ')');
                end
            end
            app.TextArea.Value = textVal;
            app.TextArea_2.Value = textVal;
            app.TextArea_3.Value = textVal;
            app.TextArea_4.Value = textVal;
        end
        
        function results = ConsolidateCustomAndPreset(app, presets, custom)
            %this function turns the presets and the custom coords into one matrix
            results = {};
            for i = 1: numel(presets)
                results{i} = presets(i);
            end
            for i = numel(presets) +1 : numel(presets) + numel(custom/2) - 1
                results{i} = custom(i - numel(presets), :);
            end
            
        end
    end

    methods (Access = private)

        % Button pushed function: AddCustomButton
        function AddCustomButtonPushed(app, event)
            app.params.customLocs = [app.params.customLocs; [app.EditField_2.Value, app.EditField.Value]];
            UpdateTextAreas(app);
        end

        % Button pushed function: AddCustomButton_2
        function AddCustomButton_2Pushed(app, event)
            app.params.customLocs = [app.params.customLocs; [app.EditField_3.Value, app.EditField_4.Value]];
            UpdateTextAreas(app);
        end

        % Button pushed function: AddCustomButton_3
        function AddCustomButton_3Pushed(app, event)
            app.params.customLocs = [app.params.customLocs; [app.EditField_5.Value, app.EditField_6.Value]];
            UpdateTextAreas(app);            
        end

        % Button pushed function: AddCustomButton_4
        function AddCustomButton_4Pushed(app, event)
            app.params.customLocs = [app.params.customLocs; [app.EditField_7.Value, app.EditField_8.Value]];
            UpdateTextAreas(app);    
        end

        % Button pushed function: 
        % DifferentLocationContrastReversingButton
        function DifferentLocationContrastReversingButtonPushed(app, event)
            %% Change locations contrast reversing gratings 
            %resetting labels
            app.Label.Text = '';
            app.StopLabel.Text = 'To stop current trial, hold down shift';
            app.Gauge.Value = 0;
            
            % setting up locations 
            numTrialsPerCenter = app.StimuliPerLocationEditField.Value;
            locations = [];
            if app.CenterCheckBox_4.Value
                locations = [locations, 5];
            end
            if app.TopLeftCheckBox_4.Value
                locations = [locations, 1];
            end
            if app.TopRightCheckBox_4.Value
                locations = [locations, 2];
            end
            if app.BottomLeftCheckBox_4.Value
                locations = [locations, 3];
            end
            if app.BottomRightCheckBox_4.Value
                locations = [locations, 4];
            end
            locationTTLs = [app.params.ulTTL, app.params.urTTL, app.params.llTTL, app.params.lrTTL, app.params.ceTTL];
            allCenters = [];
            for i = 1: numel(locations)
                allCenters = [ allCenters, locations(i)*ones(1,numTrialsPerCenter)];
            end
            permLocations = allCenters(randperm(length(allCenters)));
            
            Screen('FillRect', app.params.w, app.params.gray);
            Screen('Flip', app.params.w);
            pause(5)
            
            % setting up parameters for grating 
            app.cRParam.cps = app.CyclesperSecondEditField_3.Value;
            app.cRParam.cpd = app.CyclesperDegreeEditField_3.Value;
            app.cRParam.contrast = app.ColorContrastEditField.Value;
            app.cRParam.duration = app.StimulusDurationsEditField.Value;
            app.cRParam.orientation = app.AngleEditField_3; %to switch direction of drifiting, add 180 to orientation
            app.cRParam.gratingSize = app.GratingSizedegEditField_3.Value;
            app.cRParam.drawmask = app.MaskingCheckBox_3.Value; %1; 0 for no mask, 1 for mask
            app.cRParam.maskStd = app.cRParam.gratingSize/(app.params.pixelDeg*2*2);
            app.cRParam.numContrasts = length(allCenters);
            app.cRParam.trialDelay = [app.StimulusDelaysEditField_3.Value, app.toEditField_4.Value];
            startTime = datestr(now);
            times = strings(app.cRParam.numContrasts, 1);
            outputFileName = [app.params.stimDir, app.OutputFileNameEditField_4.Value];
            if isempty(app.OutputFileNameEditField_4.Value)
                outputFileName = [app.params.stimDir, 'Corners_Contrast', datestr(now)];
            end
            
            %header of text file
            if app.params.printTxt
                fileID = fopen([outputFileName, '.txt'],'a');
                fprintf(fileID, '\n\n\nCorners Contrast Reversing Grating Stimulus\nTime and Date\n');
                fprintf(fileID, '%s', startTime);
                fprintf(fileID, '\n\n');
                fprintf(fileID, ['Locations: 0 - whole screen; 1 - top left; 2 - top right; \n'...
                    '3 - bottom left; 4 - bottom right; 5 - center\n\n']); %legend
                fprintf(fileID, '%-10s %-25s %-10s %-10s', "trial", "start time", "location", "stimulus type"); %table top
                fprintf(fileID, '\n');
            end
            
            for i = 1:app.cRParam.numContrasts
                loc = permLocations(i);
                locTTL = locationTTLs(loc);
                
                times(i) = string(datestr(now));
                %plays a drift; if loc is 5, input to function is 0 for center
                 contrastRevGratFun(app.cRParam.cps, app.cRParam.cpd, app.cRParam.contrast, app.cRParam.duration,...
                    app.cRParam.orientation, (loc~=5)*loc, app.cRParam.gratingSize, app.cRParam.drawmask,...
                    app.cRParam.maskStd, app.params.w, app.params, locTTL, app.params.lj);
               
                Screen('FillRect', app.params.w, app.params.gray);
                Screen('Flip', app.params.w);
                app.Label.Text = ['Quadrants Contrasts ', num2str(i)];
                app.Gauge.Value = floor(100*i/app.cRParam.numContrasts);
                blankSceen_waitframes = app.cRParam.trialDelay(1) + (app.cRParam.trialDelay(2) - app.cRParam.trialDelay(1))*rand();
                pause(blankSceen_waitframes)
                
                if app.params.printTxt
                    table = vertcat(i, times(i), loc, 'qcrg');
                    fprintf(fileID, '%-10s %-25s %-10s %-10s\n', table); %table line
                end 
                
                [~, ~, code] = KbCheck; %check for abort key (shift)
                if(find(code) == 63)|(find(code) == 51) %if abort key is pressed, stop trial
                    disp("Trial aborted");
                    if app.params.printTxt
                        fprintf(fileID, 'Trial aborted');
                    end 
                    break; 
                end 
            end 
            
            app.StopLabel.Text = 'Trial End';
            if app.params.printMat
                printParams(app.cRParam, times, permLocations, startTime, outputFileName);
            end 
        end

        % Button pushed function: DifferentLocationDriftButton
        function DifferentLocationDriftButtonPushed(app, event)
            %% Change locations drift gratings 
            %resetting labels
            app.Label.Text = '';
            app.StopLabel.Text = 'To stop current trial, hold down shift';
            app.Gauge.Value = 0;
            
            % setting up locations 
            numTrialsPerCenter = app.DriftsPerLocationEditField.Value;
            locations = [];
            if app.CenterCheckBox_2.Value
                locations = [locations, 5];
            end
            if app.TopLeftCheckBox_2.Value
                locations = [locations, 1];
            end
            if app.TopRightCheckBox_2.Value
                locations = [locations, 2];
            end
            if app.BottomLeftCheckBox_2.Value
                locations = [locations, 3];
            end
            if app.BottomRightCheckBox_2.Value
                locations = [locations, 4];
            end
            locationTTLs = [app.params.ulTTL, app.params.urTTL, app.params.llTTL, app.params.lrTTL, app.params.ceTTL];
            allCenters = [];
            for i = 1: numel(locations)
                allCenters = [ allCenters, locations(i)*ones(1,numTrialsPerCenter)];
            end
            permLocations = allCenters(randperm(length(allCenters)));
            
            Screen('FillRect', app.params.w, app.params.gray);
            Screen('Flip', app.params.w);
            pause(5)
            
            % setting up parameters for grating 
            app.bDParam.cps = app.CyclesperSecondEditField.Value;
            app.bDParam.cpd = app.CyclesperDegreeEditField.Value;
            app.bDParam.contrast = app.ColorContrastEditField_3.Value;
            app.bDParam.duration = app.StaticDurationsEditField.Value;
            app.bDParam.orientation = app.AngleEditField_2.Value; %to switch direction of drifiting, add 180 to orientation
            app.bDParam.gratingSize = app.GratingSizedegEditField.Value;
            app.bDParam.drawmask = app.MaskingCheckBox.Value; %1; 0 for no mask, 1 for mask
            app.bDParam.maskStd = app.bDParam.gratingSize/(app.params.pixelDeg*2*2);
            app.bDParam.numDrifts = length(allCenters);
            app.bDParam.trialDelay = [app.StimulusDelaysEditField.Value, app.toEditField_2.Value];
            startTime = datestr(now);
            times = strings(app.bDParam.numDrifts, 1);
            outputFileName = [app.params.stimDir, app.OutputFileNameEditField_2.Value];
            if isempty(app.OutputFileNameEditField_2.Value)
                outputFileName = [app.params.stimDir, 'Corners_Static', datestr(now)];
            end
            
            %header of text file
            if app.params.printTxt
                fileID = fopen([outputFileName, '.txt'],'a');
                fprintf(fileID, '\n\n\nCorners Drifting Grating Stimulus\nTime and Date\n');
                fprintf(fileID, '%s', startTime);
                fprintf(fileID, '\n\n');
                fprintf(fileID, ['Locations: 0 - whole screen; 1 - top left; 2 - top right; \n'...
                    '3 - bottom left; 4 - bottom right; 5 - center\n\n']); %legend
                fprintf(fileID, '%-10s %-25s %-10s %-10s', "trial", "start time", "location", "stimulus type"); %table top
                fprintf(fileID, '\n');
            end
            
            for i = 1:app.bDParam.numDrifts
                loc = permLocations(i);
                locTTL = locationTTLs(loc);
                
                times(i) = string(datestr(now));
                %plays a drift; if loc is 5, input to function is 0 for center
                driftingGratingFun(app.bDParam.cps, app.bDParam.cpd, app.bDParam.contrast, app.bDParam.duration,...
                    app.bDParam.orientation, (loc~=5)*loc, app.bDParam.gratingSize, app.bDParam.drawmask,...
                    app.bDParam.maskStd, app.params.w, app.params, locTTL, app.params.lj);
                %driftingGratingFun(cps, cpd, contrast, duration, orientation, center, gratingSize, drawmask, maskStd, w,  params, TTLPin, lj, arduinoCom)
                
                Screen('FillRect', app.params.w, app.params.gray);
                Screen('Flip', app.params.w);
                app.Label.Text = ['Quadrants Drifts ', num2str(i)];
                app.Gauge.Value = floor(100*i/app.bDParam.numDrifts);
                blankSceen_waitframes = app.bDParam.trialDelay(1) + (app.bDParam.trialDelay(2) - app.bDParam.trialDelay(1))*rand();
                pause(blankSceen_waitframes)
                
                if app.params.printTxt
                    table = vertcat(i, times(i), loc, 'qdg');
                    fprintf(fileID, '%-10s %-25s %-10s %-10s\n', table); %table line
                end
                
                [~, ~, code] = KbCheck; %check for abort key (shift)
                if(find(code) == 63)|(find(code) == 51) %if abort key is pressed, stop trial
                    disp("Trial aborted");
                    if app.params.printTxt
                        fprintf(fileID, 'Trial aborted');
                    end
                    break;
                end
            end
            
            app.StopLabel.Text = 'Trial End';
            if app.params.printMat
                printParams(app.bDParam, times, permLocations, startTime, outputFileName);
            end
        end

        % Button pushed function: DifferentLocationFlashButton
        function DifferentLocationFlashButtonPushed(app, event)
            %resetting labels
            app.Label.Text = '';
            app.StopLabel.Text = 'To stop current trial, hold down shift';
            app.Gauge.Value = 0;
                        
            % setting up locations 
            numTrialsPerCenter = app.FlashesPerLocationEditField_3.Value;
            %gets locations by checking the relevant check boxes
            %Locations - 0 -center, 1 - top left quadrant, 2 - top right quadrant, 3 - bottom left
            % quadrant, 4- bottom right quadrant
            locations = [];
            if app.CenterCheckBox.Value
                locations = [locations, 0];
            end
            if app.TopLeftCheckBox.Value
                locations = [locations, 1];
            end
            if app.TopRightCheckBox.Value
                locations = [locations, 2];
            end
            if app.BottomLeftCheckBox.Value
                locations = [locations, 3];
            end
            if app.BottomRightCheckBox.Value
                locations = [locations, 4];
            end
            for i = 1:size(app.params.customLocs, 1)
                locations = [locations, i+4];
            end
           % locationTTLs = [app.params.ulTTL, app.params.urTTL, app.params.llTTL, app.params.lrTTL, app.params.ceTTL];
            %allLocations = ConsolidateCustomAndPreset(app, locations, app.params.customLocs);
            allCenters = [];
            for i = 1: numel(locations)
                allCenters = [ allCenters, locations(i)*ones(1,numTrialsPerCenter)];
            end
            permLocations = allCenters(randperm(length(allCenters)));
            
            
            % setting up parameters for grating 
            app.swParam.numFlashes = numTrialsPerCenter*numel(locations);
            app.swParam.color = app.params.white;
            app.swParam.whiteSize = 25;
            app.swParam.flashDelay = [app.FlashDelaysEditField.Value app.toEditField.Value];
            app.swParam.flashDuration = app.FlashDurationsEditField.Value;%1/params.fps;
            app.swParam.waitBlack = app.InitialBlackScreenCheckBox.Value*60;
            times = strings(app.swParam.numFlashes, 1);
            outputFileName = app.OutputFileNameEditField.Value;
            if isempty(app.OutputFileNameEditField.Value)
                 outputFileName = [app.params.stimDir, 'Corners_Flash', datestr(now) ];
            end
            startTime = datestr(now);
            
            if app.params.printTxt
                %header of text file
                fileID = fopen([outputFileName, '.txt'],'a');
                fprintf(fileID, '\n\n\nMultiple Location Flash Stimulus\nTime and Date\n');
                fprintf(fileID, '%s', startTime);
                fprintf(fileID, '\n\n');
                fprintf(fileID, ['Locations: 0 - whole screen; 1 - top left; 2 - top right; \n'...
                    '3 - bottom left; 4 - bottom right; 5 - center\n\n']); %legend
                fprintf(fileID, '%-10s %-25s %-10s %-10s', "trial", "start time", "location", "stimulus type"); %table top
                fprintf(fileID, '\n');
            end
            
            
            for i = 1:app.swParam.numFlashes
                if (i == 1) & (app.swParam.waitBlack ~= 0)
                    Screen('FillRect', app.params.w, app.params.black);
                    Screen('Flip', app.params.w);
                    disp('Screen black for 60 seconds')
                    WaitSecs(app.swParam.waitBlack)
                end
                
                tempLoc = permLocations(i)
                loc = [];
                if tempLoc > 4
                    loc = app.params.customLocs(tempLoc - 4, :).*app.params.dims;
                else
                    loc = tempLoc
                end
                
                %locTTL = locationTTLs(loc);

                times(i) = string(datestr(now)); 
                coloredLocScreenFlash(app.swParam.flashDuration, app.swParam.color, loc, app.swParam.whiteSize, app.params.w, app.params, 15, app.params.lj);
                
                Screen('FillRect', app.params.w, app.params.black);
                Screen('Flip', app.params.w);
                app.Label.Text = ['Quadrants White Flash ', num2str(i)];
                app.Gauge.Value = floor(100*i/app.swParam.numFlashes);
                blankSceen_waitframes = app.swParam.flashDelay(1) + (app.swParam.flashDelay(2) - app.swParam.flashDelay(1))*rand();
                pause(blankSceen_waitframes)
                
                if app.params.printTxt
                    table = vertcat(i, times(i), loc, 'qwf');
                    fprintf(fileID, '%-10s %-25s %-10s %-10s\n', table); %table line
                end
                
                [~, ~, code] = KbCheck; %check for abort key (shift)
                
                if(find(code) == 63)|(find(code) == 51) %if abort key is pressed, stop trial
                    if app.params.printTxt
                        fprintf(fileID, 'Trial aborted');
                    end
                    break;
                end
            end
            
            app.StopLabel.Text = 'Trial End';
            if app.params.printMat
                printParams(app.swParam, times, permLocations, startTime, outputFileName);
            end
        end

        % Button pushed function: DifferentLocationStaticButton
        function DifferentLocationStaticButtonPushed(app, event)
            %% Change locations static gratings 
            %resetting labels
            app.Label.Text = '';
            app.StopLabel.Text = 'To stop current trial, hold down shift';
            app.Gauge.Value = 0;
            % setting up locations 
            numTrialsPerCenter = app.StaticsPerLocationEditField.Value;
            locations = [];
            if app.CenterCheckBox_3.Value
                locations = [locations, 5];
            end
            if app.TopLeftCheckBox_3.Value
                locations = [locations, 1];
            end
            if app.TopRightCheckBox_3.Value
                locations = [locations, 2];
            end
            if app.BottomLeftCheckBox_3.Value
                locations = [locations, 3];
            end
            if app.BottomRightCheckBox_3.Value
                locations = [locations, 4];
            end
            locationTTLs = [app.params.ulTTL, app.params.urTTL, app.params.llTTL, app.params.lrTTL, app.params.ceTTL];
            allCenters = [];
            for i = 1: numel(locations)
                allCenters = [ allCenters, locations(i)*ones(1,numTrialsPerCenter)];
            end
            permLocations = allCenters(randperm(length(allCenters)));
            
            Screen('FillRect', app.params.w, app.params.gray);
            Screen('Flip', app.params.w);
            pause(5)
            
            % setting up parameters for grating 
            app.bDParam.cps = app.CyclesperSecondEditField_2.Value;
            app.bDParam.cpd = app.CyclesperDegreeEditField_2.Value;
            app.bDParam.contrast = app.ColorContrastEditField_2.Value;
            app.bDParam.duration = app.StaticDurationsEditField.Value;
            app.bDParam.orientation = app.AngleEditField_2; %to switch direction of drifiting, add 180 to orientation
            app.bDParam.gratingSize = app.GratingSizedegEditField_2.Value;
            app.bDParam.drawmask = app.MaskingCheckBox_2.Value; %1; 0 for no mask, 1 for mask
            app.bDParam.maskStd = app.bDParam.gratingSize/(app.params.pixelDeg*2*2);
            app.bDParam.numDrifts = length(allCenters);
            app.bDParam.trialDelay = [app.StimulusDelaysEditField_2.Value, app.toEditField_3.Value];
            startTime = datestr(now);
            times = strings(app.bDParam.numDrifts, 1);
            
            outputFileName = [app.params.stimDir, app.OutputFileNameEditField_3.Value]
            
            if isempty(app.OutputFileNameEditField_3.Value)
                outputFileName = [app.params.stimDir, 'Corners_Static', datestr(now)];
            end
            
            %header of text file
            if app.params.printTxt
                fileID = fopen([outputFileName, '.txt'],'a');
                fprintf(fileID, '\n\n\nCorners Static Grating Stimulus\nTime and Date\n');
                fprintf(fileID, '%s', startTime);
                fprintf(fileID, '\n\n');
                fprintf(fileID, ['Locations: 0 - whole screen; 1 - top left; 2 - top right; \n'...
                    '3 - bottom left; 4 - bottom right; 5 - center\n\n']); %legend
                fprintf(fileID, '%-10s %-25s %-10s %-10s', "trial", "start time", "location", "stimulus type"); %table top
                fprintf(fileID, '\n');
            end
            
            for i = 1:app.bDParam.numDrifts
                loc = permLocations(i);
                locTTL = locationTTLs(loc);
                
                times(i) = string(datestr(now));
                %plays a drift; if loc is 5, input to function is 0 for center
                driftingGratingFun(app.bDParam.cps, app.bDParam.cpd, app.bDParam.contrast, app.bDParam.duration,...
                    app.bDParam.orientation, (loc~=5)*loc, app.bDParam.gratingSize, app.bDParam.drawmask,...
                    app.bDParam.maskStd, app.params.w, app.params, locTTL, app.params.lj);
                %driftingGratingFun(cps, cpd, contrast, duration, orientation, center, gratingSize, drawmask, maskStd, w,  params, TTLPin, lj, arduinoCom)
                
                Screen('FillRect', app.params.w, app.params.gray);
                Screen('Flip', app.params.w);
                app.Label.Text = ['Quadrants Statics ', num2str(i)];
                app.Gauge.Value = floor(100*i/app.bDParam.numDrifts);
                blankSceen_waitframes = app.bDParam.trialDelay(1) + (app.bDParam.trialDelay(2) - app.bDParam.trialDelay(1))*rand();
                pause(blankSceen_waitframes)
                
                if app.params.printTxt
                    table = vertcat(i, times(i), loc, 'qsg');
                    fprintf(fileID, '%-10s %-25s %-10s %-10s\n', table); %table line
                end
                
                [~, ~, code] = KbCheck; %check for abort key (shift)
                if(find(code) == 63)|(find(code) == 51) %if abort key is pressed, stop trial
                    disp("Trial aborted");
                    if app.params.printTxt
                        fprintf(fileID, 'Trial aborted');
                    end
                    break;
                end
            end
            
            app.StopLabel.Text = 'Trial End';
            if app.params.printMat
                printParams(app.bDParam, times, permLocations, startTime, outputFileName);
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            sca;
            Priority(0);
            app.params.lj.close;
            disp("Experiment End");
            closereq;
        end

        % Button pushed function: RemoveCustomButton
        function RemoveCustomButtonPushed(app, event)
             app.params.customLocs = app.params.customLocs(1:end-1,:);
             UpdateTextAreas(app);
        end

        % Button pushed function: RemoveCustomButton_2
        function RemoveCustomButton_2Pushed(app, event)
             app.params.customLocs = app.params.customLocs(1:end-1,:);
             UpdateTextAreas(app);
        end

        % Button pushed function: RemoveCustomButton_3
        function RemoveCustomButton_3Pushed(app, event)
             app.params.customLocs = app.params.customLocs(1:end-1,:);
             UpdateTextAreas(app);            
        end

        % Button pushed function: RemoveCustomButton_4
        function RemoveCustomButton_4Pushed(app, event)
             app.params.customLocs = app.params.customLocs(1:end-1,:);
             UpdateTextAreas(app);            
        end

        % Button pushed function: SetupMonitorButton
        function SetupMonitorButtonPushed(app, event)
            %performs setup for monitor and TTL
            close all
            clc
            sca
            app.params.stimDir = '/home/adeeti/Dropbox/ProektLab_code/Adeeti_code/PsychoPhysics/stimuli/AdeetiStim/';
            cd(app.params.stimDir);
            addpath(genpath(app.params.stimDir));
            
            % Here we call some default settings for setting up Psychtoolbox
            clear params
            rng('shuffle')
            
            % Setup PTB with some default values
            PsychDefaultSetup(2);
            %PsychImaging('PrepareConfiguration');
            %PsychImaging('AddTask', 'FinalFormatting', 'DisplayColorCorrection', 'SimpleGamma');
            
            %Mouse details
            app.params.distanceToMonitor = app.DistancetoMonitorcmEditField.Value; %cm
            app.params.mouseID = app.MouseIDEditField.Value; 
            app.params.Date= date;
            
            % Computer details
            app.params.monitorInUse = app.MonitorEditField.Value;
            app.params.os = app.OSEditField.Value; %1 for on linux, 0 for windows, 2 for mac
            
            % TTL details
            app.params.useArduino = app.UseArduinoCheckBox.Value; 
            app.params.useLabJack = app.UseLabJackCheckBox.Value;
            app.params.startTTL = app.TrialStartTTLPinEditField.Value; %number of pin for TTL for the start of a trial
            app.params.ulTTL = app.UpperLeftTTLPinEditField.Value; %number of pin for TTL for the start of a grating in the upper left 
            app.params.urTTL = app.UpperRightTTLPinEditField.Value; %number of pin for TTL for the start of a grating in the upper right 
            app.params.llTTL = app.LowerLeftTTLPinEditField.Value; %number of pin for TTL for the start of a grating in the lower left 
            app.params.lrTTL = app.LowerRightTTLPinEditField.Value; %number of pin for TTL for the start of a grating in the lower right 
            app.params.ceTTL = app.CenterTTLPinEditField.Value; %number of pin for TTL for the start of a grating in the center 
            
            %params file output details
            app.params.printTxt = app.PrintTrialstotxtFileCheckBox.Value;
            app.params.printMat = app.PrintTrialstomatFileCheckBox.Value;
            
            %custom locations matrix initiated
            app.params.customLocs = [];
            
            app.SetupMonitorButton.Text = 'Setup Complete!';
            all_screens = Screen('Screens');
            
            % If more than one monitor connected, display stimuli on all
            % non-primary monitors. If only one monitor connected, display stimuli
            % on that monitor.
            
            if max(all_screens) == 0
                screens = all_screens;
                warning('No external monitors are connected. Are you sure you want to continue?')
            else
                screens = all_screens(all_screens > 0);
            end
            screenNumber = max(screens);
            
            %save screen and screen colors
            app.params.screenNumber = screenNumber;
            app.params.white = WhiteIndex(screenNumber);
            app.params.black = BlackIndex(screenNumber);
            app.params.gray = app.params.white/2;
            [app.params.w, ~]=PsychImaging('OpenWindow',app.params.screenNumber, app.params.gray);
            
            app.params = configureScreens_final(app.params.w, app.params); % Set up monitors and initialize blank screen - grey background
            
            Screen('BlendFunction', app.params.w, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);
            
            AssertOpenGL
            %oldGamma =Screen('LoadNormalizedGammaTable', w, params.gammaTable');
            
            %maximize priority for good timing
            topPriorityLevel = MaxPriority(app.params.w);
            Priority(topPriorityLevel);
            
            
            %% setting up TTL device
            if app.params.useLabJack
                %lj = labJack('verbose', true, 'deviceID', 3);
                app.params.lj = labJack('verbose', false, 'deviceID', 3);
            end
            if app.params.useArduino
                if ~exist('arduinoCom')
                    if app.params.os ==1
                        % ln -s /dev/ttyACM0 /dev/ttyS101
                        arduinoCom = serial('/dev/ttyACM0','BaudRate',9600); % insert your serial  LINUX
                    end
                end
                if strcmpi(arduinoCom.Status, 'open')
                    fclose(arduinoCom);
                end
                fopen(arduinoCom);
                pause(3)
            end
            
            Screen('FillRect', app.params.w, app.params.black);
            Screen('Flip', app.params.w);
            
        end

        % Button pushed function: WholeScreenContrastReversingButton
        function WholeScreenContrastReversingButtonPushed(app, event)
            %resetting labels
            app.Label.Text = 'Trial Number:';
            app.StopLabel.Text = 'To stop current trial, hold down shift';
            app.Gauge.Value = 0;
            
            Screen('FillRect', app.params.w, app.params.gray);
            Screen('Flip', app.params.w);
            pause(5)
            
            app.cRParam.cps = app.CyclesperSecondEditField_3.Value;
            app.cRParam.cpd = app.CyclesperDegreeEditField_3.Value;
            app.cRParam.contrast = app.ColorContrastEditField.Value;
            app.cRParam.duration = app.StimulusDurationsEditField.Value;
            app.cRParam.orientation = app.AngleEditField_3.Value; %to switch direction of drifiting, add 180 to orientation
            app.cRParam.center = find(ismember(app.CenterLocationDropDown_3.Items, app.CenterLocationDropDown_3.Value), 1) - 1; %finds associated value of selected location
            app.cRParam.gratingSize = 70;
            app.cRParam.drawmask = app.MaskingCheckBox_3.Value; %1; 0 for no mask, 1 for mask
            app.cRParam.maskStd = app.cRParam.gratingSize/(app.params.pixelDeg*2*2);
            app.cRParam.numContrasts = app.TotalStimuliEditField.Value;
            app.cRParam.trialDelay = [app.StimulusDelaysEditField_3.Value, app.toEditField_4.Value];
            startTime = datestr(now);
            times = strings(app.cRParam.numContrasts, 1);
            outputFileName = [app.params.stimDir, app.OutputFileNameEditField_4.Value];
            if isempty(app.OutputFileNameEditField_4.Value)
                outputFileName = [app.params.stimDir, 'Big_Drift', datestr(now)];
            end
            
            %header of text file
            if app.params.printTxt
                    fileID = fopen([outputFileName, '.txt'],'a');
                    fprintf(fileID, '\n\n\nFull Screen Drifting Grating Stimulus\nTime and Date\n');
                    fprintf(fileID, '%s', startTime);
                    fprintf(fileID, '\n\n');
                    fprintf(fileID, ['Locations: 0 - whole screen; 1 - top left; 2 - top right; \n'...
                        '3 - bottom left; 4 - bottom right; 5 - center\n\n']); %legend
                    fprintf(fileID, '%-10s %-25s %-10s %-10s', "trial", "start time", "location", "stimulus type"); %table top
                    fprintf(fileID, '\n');
            end
            
            for i = 1:app.cRParam.numContrasts
                times(i) = string(datestr(now)); 
                 contrastRevGratFun(app.cRParam.cps, app.cRParam.cpd, app.cRParam.contrast, app.cRParam.duration,...
                    app.cRParam.orientation, app.cRParam.center,...
                    app.cRParam.gratingSize, app.cRParam.drawmask, app.cRParam.maskStd, app.params.w, app.params, app.params.startTTL, app.params.lj);
                
                Screen('FillRect', app.params.w, app.params.gray);
                Screen('Flip', app.params.w);
                app.Label.Text = ['Full Screen Drift Trial: ', num2str(i)];
                app.Gauge.Value = floor(100*i/app.cRParam.numContrasts);
                blankSceen_waitframes = app.cRParam.trialDelay(1) + (app.cRParam.trialDelay(2) - app.cRParam.trialDelay(1))*rand();
                pause(blankSceen_waitframes)
                
                if app.params.printTxt
                    table = vertcat(i, times(i), '0', 'fscrg');
                    fprintf(fileID, '%-10s %-25s %-10s %-10s\n', table); %table line
                end
                
                [~, ~, code] = KbCheck; %check for abort key (shift)
                if(find(code) == 63)|(find(code) == 51) %if abort key is pressed, stop trial
                    disp("Trial aborted");
                    if app.params.printTxt
                        fprintf(fileID, 'Trial aborted');
                    end
                    break;
                end
            end
            
            app.Label.Text = 'Trial End';
            if app.params.printMat
                printParams(app.cRParam, times, zeros(size(times)), startTime, outputFileName);
            end            
        end

        % Button pushed function: WholeScreenDriftButton
        function WholeScreenDriftButtonPushed(app, event)
            %resetting labels
            app.Label.Text = 'Trial Number:';
            app.StopLabel.Text = 'To stop current trial, hold down shift';
            app.Gauge.Value = 0;
            
            Screen('FillRect', app.params.w, app.params.gray);
            Screen('Flip', app.params.w);
            pause(5)
            
            app.bDParam.cps = app.CyclesperSecondEditField.Value;
            app.bDParam.cpd = app.CyclesperDegreeEditField.Value;
            app.bDParam.contrast = app.ColorContrastEditField_3.Value;
            app.bDParam.duration =app.DriftDurationsEditField.Value;
            app.bDParam.orientation = app.AngleEditField.Value; %to switch direction of drifiting, add 180 to orientation
            app.bDParam.center = find(ismember(app.CenterLocationDropDown.Items, app.CenterLocationDropDown.Value), 1) - 1; %finds associated value of selected location
            app.bDParam.gratingSize = 70;
            app.bDParam.drawmask = app.MaskingCheckBox.Value; %1; 0 for no mask, 1 for mask
            app.bDParam.maskStd = app.bDParam.gratingSize/(app.params.pixelDeg*2*2);
            app.bDParam.numDrifts = app.TotalDriftsEditField.Value;
            app.bDParam.trialDelay = [app.StimulusDelaysEditField.Value, app.toEditField_2.Value];
            startTime = datestr(now);
            times = strings(app.bDParam.numDrifts, 1);
            outputFileName = [app.params.stimDir, app.OutputFileNameEditField_3.Value];
            if isempty(app.OutputFileNameEditField_3.Value)
                outputFileName = [app.params.stimDir, 'Big_Drift', datestr(now)];
            end
            
            %header of text file
            if app.params.printTxt
                    fileID = fopen([outputFileName, '.txt'],'a');
                    fprintf(fileID, '\n\n\nFull Screen Drifting Grating Stimulus\nTime and Date\n');
                    fprintf(fileID, '%s', startTime);
                    fprintf(fileID, '\n\n');
                    fprintf(fileID, ['Locations: 0 - whole screen; 1 - top left; 2 - top right; \n'...
                        '3 - bottom left; 4 - bottom right; 5 - center\n\n']); %legend
                    fprintf(fileID, '%-10s %-25s %-10s %-10s', "trial", "start time", "location", "stimulus type"); %table top
                    fprintf(fileID, '\n');
            end
            
            for i = 1:app.bDParam.numDrifts
                times(i) = string(datestr(now)); 
                driftingGratingFun(app.bDParam.cps, app.bDParam.cpd, app.bDParam.contrast, app.bDParam.duration,...
                    app.bDParam.orientation, app.bDParam.center,...
                    app.bDParam.gratingSize, app.bDParam.drawmask, app.bDParam.maskStd, app.params.w, app.params, app.params.startTTL, app.params.lj);
                
                Screen('FillRect', app.params.w, app.params.gray);
                Screen('Flip', app.params.w);
                app.Label.Text = ['Full Screen Drift Trial: ', num2str(i)];
                app.Gauge.Value = floor(100*i/app.bDParam.numDrifts);
                blankSceen_waitframes = app.bDParam.trialDelay(1) + (app.bDParam.trialDelay(2) - app.bDParam.trialDelay(1))*rand();
                pause(blankSceen_waitframes)
                
                if app.params.printTxt
                    table = vertcat(i, times(i), '0', 'fsdg');
                    fprintf(fileID, '%-10s %-25s %-10s %-10s\n', table); %table line
                end
                
                [~, ~, code] = KbCheck; %check for abort key (shift)
                if(find(code) == 63)|(find(code) == 51) %if abort key is pressed, stop trial
                    disp("Trial aborted");
                    if app.params.printTxt
                        fprintf(fileID, 'Trial aborted');
                    end
                    break;
                end
            end
            
            app.Label.Text = 'Trial End';
            if app.params.printMat
                printParams(app.bDParam, times, zeros(size(times)), startTime, outputFileName);
            end
        end

        % Button pushed function: WholeScreenFlashButton
        function WholeScreenFlashButtonPushed(app, event)
            %resetting labels
            app.Label.Text = 'Trial Number:';
            app.StopLabel.Text = 'To stop current trial, hold down shift';
            app.Gauge.Value = 0;
            
            %% Flash, whole screen
            app.fParam.flashDelay = [app.FlashDelaysEditField.Value app.toEditField.Value];
            app.fParam.numFlashes = app.TotalFlashesEditField.Value;
            app.fParam.flashDuration = app.FlashDurationsEditField.Value;%1/params.fps;
            app.fParam.waitBlack = app.InitialBlackScreenCheckBox.Value*60;
            app.fParam.color = app.params.white;
            app.fParam.whiteSize = 70;
            times = strings(app.fParam.numFlashes, 1);
            outputFileName = [app.params.stimDir, app.OutputFileNameEditField.Value];
            
            if isempty(app.OutputFileNameEditField.Value)
                outputFileName = [app.params.stimDir, 'Big_Flash', datestr(now)];
            end
                        
            startTime = datestr(now);
            
            if app.params.printTxt
                %header of text file
                fileID = fopen([outputFileName, '.txt'],'a');
                fprintf(fileID, '\n\n\nFull Screen Flash Stimulus\nTime and Date\n');
                fprintf(fileID, '%s', startTime);
                fprintf(fileID, '\n\n');
                fprintf(fileID, ['Locations: 0 - whole screen; 1 - top left; 2 - top right; \n'...
                    '3 - bottom left; 4 - bottom right; 5 - center\n\n']); %legend
                fprintf(fileID, '%-10s %-25s %-10s %-10s', "trial", "start time", "location", "stimulus type"); %table top
                fprintf(fileID, '\n');
            end
            
            
            %times = whiteScreenFlash(app.fParam.flashDelay, app.fParam.numFlashes, app.fParam.flashDuration, app.fParam.waitBlack, outputFileName, app.params.w, app.params, app.params.lj);
            for i = 1:app.fParam.numFlashes
                if (i == 1) & (app.fParam.waitBlack ~= 0)
                    Screen('FillRect', app.params.w, app.params.black);
                    Screen('Flip', app.params.w);
                    disp('Screen black for 60 seconds')
                    WaitSecs(app.fParam.waitBlack)
                end
            
                times(i) = string(datestr(now)); 
                coloredLocScreenFlash(app.fParam.flashDuration, app.fParam.color, 0, app.fParam.whiteSize, app.params.w, app.params, app.params.startTTL, app.params.lj);
                
                Screen('FillRect', app.params.w, app.params.black);
                Screen('Flip', app.params.w);
                app.Label.Text = ['Full Screen White Flash ', num2str(i)];
                app.Gauge.Value = floor(100*i/app.fParam.numFlashes);
                blankSceen_waitframes = app.fParam.flashDelay(1) + (app.fParam.flashDelay(2) - app.fParam.flashDelay(1))*rand();
                pause(blankSceen_waitframes)
                
                if app.params.printTxt
                    table = vertcat(i, times(i), '0', 'fswf');
                    fprintf(fileID, '%-10s %-25s %-10s %-10s\n', table); %table line
                end
                
                [~, ~, code] = KbCheck; %check for abort key (shift)
                
                if(find(code) == 63)|(find(code) == 51) %if abort key is pressed, stop trial
                    if app.params.printTxt
                        fprintf(fileID, 'Trial aborted');
                    end
                    break;
                end
            end
                        
            app.Label.Text = 'Trial End!';
            if app.params.printMat
                printParams(app.fParam, times, zeros(size(times)), startTime, outputFileName);
            end
        end

        % Button pushed function: WholeScreenStaticButton
        function WholeScreenStaticButtonPushed(app, event)
            %resetting labels
            app.Label.Text = 'Trial Number:';
            app.StopLabel.Text = 'To stop current trial, hold down shift';
            app.Gauge.Value = 0;
            
            Screen('FillRect', app.params.w, app.params.gray);
            Screen('Flip', app.params.w);
            pause(5)
            
            app.bDParam.cps = app.CyclesperSecondEditField_2.Value;
            app.bDParam.cpd = app.CyclesperDegreeEditField_2.Value;
            app.bDParam.contrast = app.ColorContrastEditField_2.Value;
            app.bDParam.duration =app.StaticDurationsEditField.Value;
            app.bDParam.orientation = app.AngleEditField_3.Value; %to switch direction of drifiting, add 180 to orientation
            app.bDParam.center = find(ismember(app.CenterLocationDropDown_2.Items, app.CenterLocationDropDown_2.Value), 1) - 1; %finds associated value of selected location
            app.bDParam.gratingSize = 70;
            app.bDParam.drawmask = app.MaskingCheckBox_2.Value; %1; 0 for no mask, 1 for mask
            app.bDParam.maskStd = app.bDParam.gratingSize/(app.params.pixelDeg*2*2);
            app.bDParam.numDrifts = app.TotalStaticsEditField.Value;
            app.bDParam.trialDelay = [app.StimulusDelaysEditField_2.Value, app.toEditField_3.Value];
            startTime = datestr(now);
            times = strings(app.bDParam.numDrifts, 1);
            outputFileName = [app.params.stimDir, app.OutputFileNameEditField_3.Value];
            if isempty(app.OutputFileNameEditField_3.Value)
                outputFileName = [app.params.stimDir, 'Big_Static', datestr(now)];
            end
            
            %header of text file
            if app.params.printTxt
                    fileID = fopen([outputFileName, '.txt'],'a');
                    fprintf(fileID, '\n\n\nFull Screen Static Grating Stimulus\nTime and Date\n');
                    fprintf(fileID, '%s', startTime);
                    fprintf(fileID, '\n\n');
                    fprintf(fileID, ['Locations: 0 - whole screen; 1 - top left; 2 - top right; \n'...
                        '3 - bottom left; 4 - bottom right; 5 - center\n\n']); %legend
                    fprintf(fileID, '%-10s %-25s %-10s %-10s', "trial", "start time", "location", "stimulus type"); %table top
                    fprintf(fileID, '\n');
            end
            
            for i = 1:app.bDParam.numDrifts
                times(i) = string(datestr(now)); 
                driftingGratingFun(app.bDParam.cps, app.bDParam.cpd, app.bDParam.contrast, app.bDParam.duration,...
                    app.bDParam.orientation, app.bDParam.center,...
                    app.bDParam.gratingSize, app.bDParam.drawmask, app.bDParam.maskStd, app.params.w, app.params, app.params.startTTL, app.params.lj);
                
                %driftingGratingFun(cps, cpd, contrast, duration, orientation, center, gratingSize, drawmask, maskStd, w,  params, TTLPin, lj, arduinoCom)
                
                Screen('FillRect', app.params.w, app.params.gray);
                Screen('Flip', app.params.w);
                app.Label.Text = ['Full Screen Static Trial: ', num2str(i)];
                app.Gauge.Value = floor(100*i/app.bDParam.numDrifts);
                blankSceen_waitframes = app.bDParam.trialDelay(1) + (app.bDParam.trialDelay(2) - app.bDParam.trialDelay(1))*rand();
                pause(blankSceen_waitframes)
                
                if app.params.printTxt
                    table = vertcat(i, times(i), '0', 'fssg');
                    fprintf(fileID, '%-10s %-25s %-10s %-10s\n', table); %table line
                end
                
                [~, ~, code] = KbCheck; %check for abort key (shift)
                if(find(code) == 63)|(find(code) == 51) %if abort key is pressed, stop trial
                    disp("Trial aborted");
                    if app.params.printTxt
                        fprintf(fileID, 'Trial aborted');
                    end
                    break;
                end
            end
            
            app.Label.Text = 'Trial End';
            if app.params.printMat
                printParams(app.bDParam, times, zeros(size(times)), startTime, outputFileName);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.DistancetoMonitorcmEditFieldLabel = uilabel(app.SetupTab);
            app.DistancetoMonitorcmEditFieldLabel.Text = 'Distance to Monitor (cm)';
            app.DistancetoMonitorcmEditField = uieditfield(app.SetupTab, 'numeric');
            app.DistancetoMonitorcmEditField.Limits = [0 Inf];
            app.DistancetoMonitorcmEditField.Value = 30;
            app.OSEditFieldLabel = uilabel(app.SetupTab);
            app.OSEditFieldLabel.Text = 'OS';
            app.OSEditField = uieditfield(app.SetupTab, 'numeric');
            app.MouseIDEditFieldLabel = uilabel(app.SetupTab);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.SetupTab, 'text');
            app.MouseIDEditField.Value = 'Stim Test 1';
            app.forwndows1forlinux2formacLabel = uilabel(app.SetupTab);
            app.forwndows1forlinux2formacLabel.Text = '0 for wndows, 1 for linux, 2 for mac';
            app.MonitorEditFieldLabel = uilabel(app.SetupTab);
            app.MonitorEditFieldLabel.Text = 'Monitor';
            app.MonitorEditField = uieditfield(app.SetupTab, 'text');
            app.MonitorEditField.Value = 'testing';
            app.TrialStartTTLPinEditFieldLabel = uilabel(app.SetupTab);
            app.TrialStartTTLPinEditFieldLabel.Text = 'Trial Start TTL Pin';
            app.TrialStartTTLPinEditField = uieditfield(app.SetupTab, 'numeric');
            app.TrialStartTTLPinEditField.Limits = [0 Inf];
            app.TrialStartTTLPinEditField.Value = 16;
            app.UseArduinoCheckBox = uicheckbox(app.SetupTab);
            app.UseArduinoCheckBox.Text = 'Use Arduino?';
            app.UseLabJackCheckBox = uicheckbox(app.SetupTab);
            app.UseLabJackCheckBox.Text = 'Use LabJack?';
            app.UseLabJackCheckBox.Value = true;
            app.UpperLeftTTLPinEditFieldLabel = uilabel(app.SetupTab);
            app.UpperLeftTTLPinEditFieldLabel.Text = 'Upper Left TTL Pin';
            app.UpperLeftTTLPinEditField = uieditfield(app.SetupTab, 'numeric');
            app.UpperLeftTTLPinEditField.Limits = [0 Inf];
            app.UpperLeftTTLPinEditField.Value = 17;
            app.UpperRightTTLPinEditFieldLabel = uilabel(app.SetupTab);
            app.UpperRightTTLPinEditFieldLabel.Text = 'Upper Right TTL Pin';
            app.UpperRightTTLPinEditField = uieditfield(app.SetupTab, 'numeric');
            app.UpperRightTTLPinEditField.Limits = [0 Inf];
            app.UpperRightTTLPinEditField.Value = 18;
            app.LowerLeftTTLPinEditFieldLabel = uilabel(app.SetupTab);
            app.LowerLeftTTLPinEditFieldLabel.Text = 'Lower Left TTL Pin';
            app.LowerLeftTTLPinEditField = uieditfield(app.SetupTab, 'numeric');
            app.LowerLeftTTLPinEditField.Limits = [0 Inf];
            app.LowerLeftTTLPinEditField.Value = 19;
            app.LowerRightTTLPinEditFieldLabel = uilabel(app.SetupTab);
            app.LowerRightTTLPinEditFieldLabel.Text = 'Lower Right TTL Pin';
            app.LowerRightTTLPinEditField = uieditfield(app.SetupTab, 'numeric');
            app.LowerRightTTLPinEditField.Limits = [0 Inf];
            app.LowerRightTTLPinEditField.Value = 16;
            app.MonitorSettingsLabel = uilabel(app.SetupTab);
            app.MonitorSettingsLabel.Text = 'Monitor Settings';
            app.TTLSettingsLabel = uilabel(app.SetupTab);
            app.TTLSettingsLabel.Text = 'TTL Settings';
            app.SetupMonitorButton = uibutton(app.SetupTab, 'push');
            app.SetupMonitorButton.ButtonPushedFcn = createCallbackFcn(app, @SetupMonitorButtonPushed, true);
            app.SetupMonitorButton.Text = 'Set up Monitor';
            app.PrintTrialstotxtFileCheckBox = uicheckbox(app.SetupTab);
            app.PrintTrialstotxtFileCheckBox.Text = 'Print Trials to .txt File';
            app.PrintTrialstotxtFileCheckBox.Value = true;
            app.PrintTrialstomatFileCheckBox = uicheckbox(app.SetupTab);
            app.PrintTrialstomatFileCheckBox.Text = 'Print Trials to .mat File';
            app.PrintTrialstomatFileCheckBox.Value = true;
            app.CenterTTLPinEditFieldLabel = uilabel(app.SetupTab);
            app.CenterTTLPinEditFieldLabel.Text = 'Center TTL Pin';
            app.CenterTTLPinEditField = uieditfield(app.SetupTab, 'numeric');
            app.CenterTTLPinEditField.Limits = [0 Inf];
            app.CenterTTLPinEditField.Value = 16;
            app.FlashTab = uitab(app.TabGroup);
            app.FlashTab.Title = 'Flash';
            app.FlashDelaysEditFieldLabel = uilabel(app.FlashTab);
            app.FlashDelaysEditFieldLabel.Text = 'Flash Delay (s)';
            app.FlashDelaysEditField = uieditfield(app.FlashTab, 'numeric');
            app.FlashDelaysEditField.Limits = [0 Inf];
            app.FlashDelaysEditField.Value = 3;
            app.GeneralFlashSettingsLabel = uilabel(app.FlashTab);
            app.GeneralFlashSettingsLabel.Text = 'General Flash Settings';
            app.FlashDurationsEditFieldLabel = uilabel(app.FlashTab);
            app.FlashDurationsEditFieldLabel.Text = 'Flash Duration (s)';
            app.FlashDurationsEditField = uieditfield(app.FlashTab, 'numeric');
            app.FlashDurationsEditField.Limits = [0 Inf];
            app.FlashDurationsEditField.Value = 0.1;
            app.InitialBlackScreenCheckBox = uicheckbox(app.FlashTab);
            app.InitialBlackScreenCheckBox.Text = 'Initial Black Screen';
            app.InitialBlackScreenCheckBox.Value = true;
            app.TotalBigFlashesEditFieldLabel = uilabel(app.FlashTab);
            app.TotalBigFlashesEditFieldLabel.Text = 'Total Big Flashes';
            app.TotalFlashesEditField = uieditfield(app.FlashTab, 'numeric');
            app.TotalFlashesEditField.Limits = [0 Inf];
            app.TotalFlashesEditField.Value = 100;
            app.DifferentLocationFlashSettingsLabel = uilabel(app.FlashTab);
            app.DifferentLocationFlashSettingsLabel.Text = 'Different Location Flash Settings';
            app.toEditFieldLabel = uilabel(app.FlashTab);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.FlashTab, 'numeric');
            app.toEditField.Limits = [0 Inf];
            app.toEditField.Value = 5;
            app.CenterCheckBox = uicheckbox(app.FlashTab);
            app.CenterCheckBox.Text = 'Center';
            app.TopLeftCheckBox = uicheckbox(app.FlashTab);
            app.TopLeftCheckBox.Text = 'Top Left';
            app.TopLeftCheckBox.Value = true;
            app.BottomLeftCheckBox = uicheckbox(app.FlashTab);
            app.BottomLeftCheckBox.Text = 'Bottom Left';
            app.BottomLeftCheckBox.Value = true;
            app.TopRightCheckBox = uicheckbox(app.FlashTab);
            app.TopRightCheckBox.Text = 'Top Right';
            app.TopRightCheckBox.Value = true;
            app.BottomRightCheckBox = uicheckbox(app.FlashTab);
            app.BottomRightCheckBox.Text = 'Bottom Right';
            app.BottomRightCheckBox.Value = true;
            app.FlashesPerLocationEditField_3Label = uilabel(app.FlashTab);
            app.FlashesPerLocationEditField_3Label.Text = 'Flashes Per Location';
            app.FlashesPerLocationEditField_3 = uieditfield(app.FlashTab, 'numeric');
            app.FlashesPerLocationEditField_3.Limits = [0 Inf];
            app.FlashesPerLocationEditField_3.Value = 50;
            app.DifferentLocationFlashButton = uibutton(app.FlashTab, 'push');
            app.DifferentLocationFlashButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentLocationFlashButtonPushed, true);
            app.DifferentLocationFlashButton.Text = 'Different Location Flash';
            app.WholeScreenFlashButton = uibutton(app.FlashTab, 'push');
            app.WholeScreenFlashButton.ButtonPushedFcn = createCallbackFcn(app, @WholeScreenFlashButtonPushed, true);
            app.WholeScreenFlashButton.Text = 'Whole Screen Flash';
            app.OutputFileNameEditFieldLabel = uilabel(app.FlashTab);
            app.OutputFileNameEditFieldLabel.Text = 'Output File Name';
            app.OutputFileNameEditField = uieditfield(app.FlashTab, 'text');
            app.EditField_2Label = uilabel(app.FlashTab);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.FlashTab, 'numeric');
            app.EditField_2.Limits = [0 1];
            app.Label_3 = uilabel(app.FlashTab);
            app.Label_3.Text = ',';
            app.EditField = uieditfield(app.FlashTab, 'numeric');
            app.EditField.Limits = [0 1];
            app.AddCustomButton = uibutton(app.FlashTab, 'push');
            app.AddCustomButton.ButtonPushedFcn = createCallbackFcn(app, @AddCustomButtonPushed, true);
            app.AddCustomButton.Text = 'Add Custom';
            app.Label_4 = uilabel(app.FlashTab);
            app.Label_4.Text = '';
            app.TextArea = uitextarea(app.FlashTab);
            app.TextArea.Value = {'Current Custom Locations'};
            app.RemoveCustomButton = uibutton(app.FlashTab, 'push');
            app.RemoveCustomButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCustomButtonPushed, true);
            app.RemoveCustomButton.Text = 'Remove Custom';
            app.Label_6 = uilabel(app.FlashTab);
            app.Label_6.Text = '-----------------------------------------------------------------------------------------------------------------------------------------------';
            app.DriftingGratingTab = uitab(app.TabGroup);
            app.DriftingGratingTab.Title = 'Drifting Grating';
            app.GeneralDriftSettingsLabel = uilabel(app.DriftingGratingTab);
            app.GeneralDriftSettingsLabel.Text = 'General Drift Settings';
            app.StimulusDelaysEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.StimulusDelaysEditFieldLabel.Text = 'Stimulus Delay (s)';
            app.StimulusDelaysEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.StimulusDelaysEditField.Limits = [0 Inf];
            app.StimulusDelaysEditField.Value = 3;
            app.DriftDurationsEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.DriftDurationsEditFieldLabel.Text = 'Drift Duration (s)';
            app.DriftDurationsEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.DriftDurationsEditField.Limits = [0 Inf];
            app.DriftDurationsEditField.Value = 1;
            app.TotalBigDriftsEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.TotalBigDriftsEditFieldLabel.Text = 'Total Big Drifts';
            app.TotalDriftsEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.TotalDriftsEditField.Limits = [0 Inf];
            app.TotalDriftsEditField.Value = 100;
            app.toEditField_2Label = uilabel(app.DriftingGratingTab);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.DriftingGratingTab, 'numeric');
            app.toEditField_2.Limits = [0 Inf];
            app.toEditField_2.Value = 4;
            app.DifferentLocationDriftSettingsLabel = uilabel(app.DriftingGratingTab);
            app.DifferentLocationDriftSettingsLabel.Text = 'Different Location Drift Settings';
            app.CenterCheckBox_2 = uicheckbox(app.DriftingGratingTab);
            app.CenterCheckBox_2.Text = 'Center';
            app.TopLeftCheckBox_2 = uicheckbox(app.DriftingGratingTab);
            app.TopLeftCheckBox_2.Text = 'Top Left';
            app.TopLeftCheckBox_2.Value = true;
            app.BottomLeftCheckBox_2 = uicheckbox(app.DriftingGratingTab);
            app.BottomLeftCheckBox_2.Text = 'Bottom Left';
            app.BottomLeftCheckBox_2.Value = true;
            app.TopRightCheckBox_2 = uicheckbox(app.DriftingGratingTab);
            app.TopRightCheckBox_2.Text = 'Top Right';
            app.TopRightCheckBox_2.Value = true;
            app.BottomRightCheckBox_2 = uicheckbox(app.DriftingGratingTab);
            app.BottomRightCheckBox_2.Text = 'Bottom Right';
            app.BottomRightCheckBox_2.Value = true;
            app.DifferentLocationDriftButton = uibutton(app.DriftingGratingTab, 'push');
            app.DifferentLocationDriftButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentLocationDriftButtonPushed, true);
            app.DifferentLocationDriftButton.Text = 'Different Location Drift';
            app.WholeScreenDriftButton = uibutton(app.DriftingGratingTab, 'push');
            app.WholeScreenDriftButton.ButtonPushedFcn = createCallbackFcn(app, @WholeScreenDriftButtonPushed, true);
            app.WholeScreenDriftButton.Text = 'Whole Screen Drift';
            app.DriftsPerLocationEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.DriftsPerLocationEditFieldLabel.Text = 'Drifts Per Location';
            app.DriftsPerLocationEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.DriftsPerLocationEditField.Limits = [0 Inf];
            app.DriftsPerLocationEditField.Value = 50;
            app.GratingSizedegEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.GratingSizedegEditFieldLabel.Text = 'Grating Size (deg)';
            app.GratingSizedegEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.GratingSizedegEditField.Limits = [0 Inf];
            app.GratingSizedegEditField.Value = 25;
            app.CyclesperSecondEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.CyclesperSecondEditFieldLabel.Text = 'Cycles per Second';
            app.CyclesperSecondEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.CyclesperSecondEditField.Limits = [0 Inf];
            app.CyclesperSecondEditField.Value = 3;
            app.CyclesperDegreeEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.CyclesperDegreeEditFieldLabel.Text = 'Cycles per Degree';
            app.CyclesperDegreeEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.CyclesperDegreeEditField.Limits = [0 Inf];
            app.CyclesperDegreeEditField.Value = 0.09;
            app.ColorContrastEditField_3Label = uilabel(app.DriftingGratingTab);
            app.ColorContrastEditField_3Label.Text = 'Color Contrast';
            app.ColorContrastEditField_3 = uieditfield(app.DriftingGratingTab, 'numeric');
            app.ColorContrastEditField_3.Limits = [0 1];
            app.ColorContrastEditField_3.Value = 1;
            app.MaskingCheckBox = uicheckbox(app.DriftingGratingTab);
            app.MaskingCheckBox.Text = 'Masking';
            app.MaskingCheckBox.Value = true;
            app.CenterLocationDropDownLabel = uilabel(app.DriftingGratingTab);
            app.CenterLocationDropDownLabel.Text = 'Center Location';
            app.CenterLocationDropDown = uidropdown(app.DriftingGratingTab);
            app.CenterLocationDropDown.Items = {'Center', 'Top Left', 'Top Right', 'Bottom Left', 'Bottom Right'};
            app.CenterLocationDropDown.Value = 'Center';
            app.OutputFileNameEditField_2Label = uilabel(app.DriftingGratingTab);
            app.OutputFileNameEditField_2Label.Text = 'Output File Name';
            app.OutputFileNameEditField_2 = uieditfield(app.DriftingGratingTab, 'text');
            app.AngleEditFieldLabel = uilabel(app.DriftingGratingTab);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.DriftingGratingTab, 'numeric');
            app.AngleEditField.Limits = [0 360];
            app.EditField_3 = uieditfield(app.DriftingGratingTab, 'numeric');
            app.EditField_3.Limits = [0 1];
            app.EditField_4Label = uilabel(app.DriftingGratingTab);
            app.EditField_4Label.Text = ',';
            app.EditField_4 = uieditfield(app.DriftingGratingTab, 'numeric');
            app.EditField_4.Limits = [0 1];
            app.TextArea_2Label = uilabel(app.DriftingGratingTab);
            app.TextArea_2Label.Text = '';
            app.TextArea_2 = uitextarea(app.DriftingGratingTab);
            app.TextArea_2.Value = {'Current Custom Locations'};
            app.AddCustomButton_2 = uibutton(app.DriftingGratingTab, 'push');
            app.AddCustomButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddCustomButton_2Pushed, true);
            app.AddCustomButton_2.Text = 'Add Custom';
            app.RemoveCustomButton_2 = uibutton(app.DriftingGratingTab, 'push');
            app.RemoveCustomButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveCustomButton_2Pushed, true);
            app.RemoveCustomButton_2.Text = 'Remove Custom';
            app.Label_5 = uilabel(app.DriftingGratingTab);
            app.Label_5.Text = '-----------------------------------------------------------------------------------------------------------------------------------------------';
            app.StaticGratingTab = uitab(app.TabGroup);
            app.StaticGratingTab.Title = 'Static Grating';
            app.GeneralStaticSettingsLabel = uilabel(app.StaticGratingTab);
            app.GeneralStaticSettingsLabel.Text = 'General Static Settings';
            app.DifferentLocationStaticSettingsLabel = uilabel(app.StaticGratingTab);
            app.DifferentLocationStaticSettingsLabel.Text = 'Different Location Static Settings';
            app.CenterCheckBox_3 = uicheckbox(app.StaticGratingTab);
            app.CenterCheckBox_3.Text = 'Center';
            app.TopLeftCheckBox_3 = uicheckbox(app.StaticGratingTab);
            app.TopLeftCheckBox_3.Text = 'Top Left';
            app.TopLeftCheckBox_3.Value = true;
            app.BottomLeftCheckBox_3 = uicheckbox(app.StaticGratingTab);
            app.BottomLeftCheckBox_3.Text = 'Bottom Left';
            app.BottomLeftCheckBox_3.Value = true;
            app.TopRightCheckBox_3 = uicheckbox(app.StaticGratingTab);
            app.TopRightCheckBox_3.Text = 'Top Right';
            app.TopRightCheckBox_3.Value = true;
            app.BottomRightCheckBox_3 = uicheckbox(app.StaticGratingTab);
            app.BottomRightCheckBox_3.Text = 'Bottom Right';
            app.BottomRightCheckBox_3.Value = true;
            app.MaskingCheckBox_2 = uicheckbox(app.StaticGratingTab);
            app.MaskingCheckBox_2.Text = 'Masking';
            app.MaskingCheckBox_2.Value = true;
            app.StimulusDelaysEditField_2Label = uilabel(app.StaticGratingTab);
            app.StimulusDelaysEditField_2Label.Text = 'Stimulus Delay (s)';
            app.StimulusDelaysEditField_2 = uieditfield(app.StaticGratingTab, 'numeric');
            app.StimulusDelaysEditField_2.Limits = [0 Inf];
            app.StimulusDelaysEditField_2.Value = 3;
            app.StaticDurationsEditFieldLabel = uilabel(app.StaticGratingTab);
            app.StaticDurationsEditFieldLabel.Text = 'Static Duration (s)';
            app.StaticDurationsEditField = uieditfield(app.StaticGratingTab, 'numeric');
            app.StaticDurationsEditField.Limits = [0 Inf];
            app.StaticDurationsEditField.Value = 1;
            app.TotalBigStaticsEditFieldLabel = uilabel(app.StaticGratingTab);
            app.TotalBigStaticsEditFieldLabel.Text = 'Total Big Statics';
            app.TotalStaticsEditField = uieditfield(app.StaticGratingTab, 'numeric');
            app.TotalStaticsEditField.Limits = [0 Inf];
            app.TotalStaticsEditField.Value = 100;
            app.toEditField_3Label = uilabel(app.StaticGratingTab);
            app.toEditField_3Label.Text = 'to';
            app.toEditField_3 = uieditfield(app.StaticGratingTab, 'numeric');
            app.toEditField_3.Limits = [0 Inf];
            app.toEditField_3.Value = 4;
            app.StaticsPerLocationEditFieldLabel = uilabel(app.StaticGratingTab);
            app.StaticsPerLocationEditFieldLabel.Text = 'Statics Per Location';
            app.StaticsPerLocationEditField = uieditfield(app.StaticGratingTab, 'numeric');
            app.StaticsPerLocationEditField.Limits = [0 Inf];
            app.StaticsPerLocationEditField.Value = 50;
            app.GratingSizedegEditField_2Label = uilabel(app.StaticGratingTab);
            app.GratingSizedegEditField_2Label.Text = 'Grating Size (deg)';
            app.GratingSizedegEditField_2 = uieditfield(app.StaticGratingTab, 'numeric');
            app.GratingSizedegEditField_2.Limits = [0 Inf];
            app.GratingSizedegEditField_2.Value = 25;
            app.CyclesperSecondEditField_2Label = uilabel(app.StaticGratingTab);
            app.CyclesperSecondEditField_2Label.Text = 'Cycles per Second';
            app.CyclesperSecondEditField_2 = uieditfield(app.StaticGratingTab, 'numeric');
            app.CyclesperSecondEditField_2.Limits = [0 Inf];
            app.CyclesperDegreeEditField_2Label = uilabel(app.StaticGratingTab);
            app.CyclesperDegreeEditField_2Label.Text = 'Cycles per Degree';
            app.CyclesperDegreeEditField_2 = uieditfield(app.StaticGratingTab, 'numeric');
            app.CyclesperDegreeEditField_2.Limits = [0 Inf];
            app.CyclesperDegreeEditField_2.Value = 0.09;
            app.ColorContrastEditField_2Label = uilabel(app.StaticGratingTab);
            app.ColorContrastEditField_2Label.Text = 'Color Contrast';
            app.ColorContrastEditField_2 = uieditfield(app.StaticGratingTab, 'numeric');
            app.ColorContrastEditField_2.Limits = [0 1];
            app.ColorContrastEditField_2.Value = 1;
            app.CenterLocationDropDown_2Label = uilabel(app.StaticGratingTab);
            app.CenterLocationDropDown_2Label.Text = 'Center Location';
            app.CenterLocationDropDown_2 = uidropdown(app.StaticGratingTab);
            app.CenterLocationDropDown_2.Items = {'Center', 'Top Left', 'Top Right', 'Bottom Left', 'Bottom Right'};
            app.CenterLocationDropDown_2.Value = 'Center';
            app.DifferentLocationStaticButton = uibutton(app.StaticGratingTab, 'push');
            app.DifferentLocationStaticButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentLocationStaticButtonPushed, true);
            app.DifferentLocationStaticButton.Text = 'Different Location Static';
            app.WholeScreenStaticButton = uibutton(app.StaticGratingTab, 'push');
            app.WholeScreenStaticButton.ButtonPushedFcn = createCallbackFcn(app, @WholeScreenStaticButtonPushed, true);
            app.WholeScreenStaticButton.Text = 'Whole Screen Static';
            app.OutputFileNameEditField_3Label = uilabel(app.StaticGratingTab);
            app.OutputFileNameEditField_3Label.Text = 'Output File Name';
            app.OutputFileNameEditField_3 = uieditfield(app.StaticGratingTab, 'text');
            app.AngleEditField_2Label = uilabel(app.StaticGratingTab);
            app.AngleEditField_2Label.Text = 'Angle';
            app.AngleEditField_2 = uieditfield(app.StaticGratingTab, 'numeric');
            app.AngleEditField_2.Limits = [0 180];
            app.Label_7 = uilabel(app.StaticGratingTab);
            app.Label_7.Text = '-----------------------------------------------------------------------------------------------------------------------------------------------';
            app.EditField_5 = uieditfield(app.StaticGratingTab, 'numeric');
            app.EditField_5.Limits = [0 1];
            app.EditField_6Label = uilabel(app.StaticGratingTab);
            app.EditField_6Label.Text = ',';
            app.EditField_6 = uieditfield(app.StaticGratingTab, 'numeric');
            app.EditField_6.Limits = [0 1];
            app.TextArea_3Label = uilabel(app.StaticGratingTab);
            app.TextArea_3Label.Text = '';
            app.TextArea_3 = uitextarea(app.StaticGratingTab);
            app.TextArea_3.Value = {'Current Custom Locations'};
            app.AddCustomButton_3 = uibutton(app.StaticGratingTab, 'push');
            app.AddCustomButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddCustomButton_3Pushed, true);
            app.AddCustomButton_3.Text = 'Add Custom';
            app.RemoveCustomButton_3 = uibutton(app.StaticGratingTab, 'push');
            app.RemoveCustomButton_3.ButtonPushedFcn = createCallbackFcn(app, @RemoveCustomButton_3Pushed, true);
            app.RemoveCustomButton_3.Text = 'Remove Custom';
            app.ContrastReversingGratingTab = uitab(app.TabGroup);
            app.ContrastReversingGratingTab.Title = 'Contrast Reversing Grating';
            app.GeneralContrastReversingSettingsLabel = uilabel(app.ContrastReversingGratingTab);
            app.GeneralContrastReversingSettingsLabel.Text = 'General Contrast Reversing Settings';
            app.DifferentLocationContrastReversingSettingsLabel = uilabel(app.ContrastReversingGratingTab);
            app.DifferentLocationContrastReversingSettingsLabel.Text = 'Different Location Contrast Reversing Settings';
            app.CenterCheckBox_4 = uicheckbox(app.ContrastReversingGratingTab);
            app.CenterCheckBox_4.Text = 'Center';
            app.TopLeftCheckBox_4 = uicheckbox(app.ContrastReversingGratingTab);
            app.TopLeftCheckBox_4.Text = 'Top Left';
            app.TopLeftCheckBox_4.Value = true;
            app.BottomLeftCheckBox_4 = uicheckbox(app.ContrastReversingGratingTab);
            app.BottomLeftCheckBox_4.Text = 'Bottom Left';
            app.BottomLeftCheckBox_4.Value = true;
            app.TopRightCheckBox_4 = uicheckbox(app.ContrastReversingGratingTab);
            app.TopRightCheckBox_4.Text = 'Top Right';
            app.TopRightCheckBox_4.Value = true;
            app.BottomRightCheckBox_4 = uicheckbox(app.ContrastReversingGratingTab);
            app.BottomRightCheckBox_4.Text = 'Bottom Right';
            app.BottomRightCheckBox_4.Value = true;
            app.MaskingCheckBox_3 = uicheckbox(app.ContrastReversingGratingTab);
            app.MaskingCheckBox_3.Text = 'Masking';
            app.MaskingCheckBox_3.Value = true;
            app.DifferentLocationContrastReversingButton = uibutton(app.ContrastReversingGratingTab, 'push');
            app.DifferentLocationContrastReversingButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentLocationContrastReversingButtonPushed, true);
            app.DifferentLocationContrastReversingButton.Text = 'Different Location Contrast Reversing';
            app.WholeScreenContrastReversingButton = uibutton(app.ContrastReversingGratingTab, 'push');
            app.WholeScreenContrastReversingButton.ButtonPushedFcn = createCallbackFcn(app, @WholeScreenContrastReversingButtonPushed, true);
            app.WholeScreenContrastReversingButton.Text = 'Whole Screen Contrast Reversing';
            app.StimulusDelaysEditField_3Label = uilabel(app.ContrastReversingGratingTab);
            app.StimulusDelaysEditField_3Label.Text = 'Stimulus Delay (s)';
            app.StimulusDelaysEditField_3 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.StimulusDelaysEditField_3.Limits = [0 Inf];
            app.StimulusDelaysEditField_3.Value = 3;
            app.StimulusDurationsEditFieldLabel = uilabel(app.ContrastReversingGratingTab);
            app.StimulusDurationsEditFieldLabel.Text = 'Stimulus Duration (s)';
            app.StimulusDurationsEditField = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.StimulusDurationsEditField.Limits = [0 Inf];
            app.StimulusDurationsEditField.Value = 1;
            app.TotalBigStimuliEditFieldLabel = uilabel(app.ContrastReversingGratingTab);
            app.TotalBigStimuliEditFieldLabel.Text = 'Total Big Stimuli';
            app.TotalStimuliEditField = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.TotalStimuliEditField.Limits = [0 Inf];
            app.TotalStimuliEditField.Value = 100;
            app.toEditField_4Label = uilabel(app.ContrastReversingGratingTab);
            app.toEditField_4Label.Text = 'to';
            app.toEditField_4 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.toEditField_4.Limits = [0 Inf];
            app.toEditField_4.Value = 4;
            app.StimuliPerLocationEditFieldLabel = uilabel(app.ContrastReversingGratingTab);
            app.StimuliPerLocationEditFieldLabel.Text = 'Stimuli Per Location';
            app.StimuliPerLocationEditField = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.StimuliPerLocationEditField.Limits = [0 Inf];
            app.StimuliPerLocationEditField.Value = 50;
            app.GratingSizedegEditField_3Label = uilabel(app.ContrastReversingGratingTab);
            app.GratingSizedegEditField_3Label.Text = 'Grating Size (deg)';
            app.GratingSizedegEditField_3 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.GratingSizedegEditField_3.Limits = [0 Inf];
            app.GratingSizedegEditField_3.Value = 25;
            app.CyclesperSecondEditField_3Label = uilabel(app.ContrastReversingGratingTab);
            app.CyclesperSecondEditField_3Label.Text = 'Cycles per Second';
            app.CyclesperSecondEditField_3 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.CyclesperSecondEditField_3.Limits = [0 Inf];
            app.CyclesperSecondEditField_3.Value = 3;
            app.CyclesperDegreeEditField_3Label = uilabel(app.ContrastReversingGratingTab);
            app.CyclesperDegreeEditField_3Label.Text = 'Cycles per Degree';
            app.CyclesperDegreeEditField_3 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.CyclesperDegreeEditField_3.Limits = [0 Inf];
            app.CyclesperDegreeEditField_3.Value = 0.09;
            app.ColorContrastEditFieldLabel = uilabel(app.ContrastReversingGratingTab);
            app.ColorContrastEditFieldLabel.Text = 'Color Contrast';
            app.ColorContrastEditField = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.ColorContrastEditField.Limits = [0 1];
            app.ColorContrastEditField.Value = 1;
            app.CenterLocationDropDown_3Label = uilabel(app.ContrastReversingGratingTab);
            app.CenterLocationDropDown_3Label.Text = 'Center Location';
            app.CenterLocationDropDown_3 = uidropdown(app.ContrastReversingGratingTab);
            app.CenterLocationDropDown_3.Items = {'Center', 'Top Left', 'Top Right', 'Bottom Left', 'Bottom Right'};
            app.CenterLocationDropDown_3.Value = 'Center';
            app.OutputFileNameEditField_4Label = uilabel(app.ContrastReversingGratingTab);
            app.OutputFileNameEditField_4Label.Text = 'Output File Name';
            app.OutputFileNameEditField_4 = uieditfield(app.ContrastReversingGratingTab, 'text');
            app.AngleEditField_3Label = uilabel(app.ContrastReversingGratingTab);
            app.AngleEditField_3Label.Text = 'Angle';
            app.AngleEditField_3 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.AngleEditField_3.Limits = [0 180];
            app.Label_8 = uilabel(app.ContrastReversingGratingTab);
            app.Label_8.Text = '-----------------------------------------------------------------------------------------------------------------------------------------------';
            app.EditField_7 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.EditField_7.Limits = [0 1];
            app.EditField_8Label = uilabel(app.ContrastReversingGratingTab);
            app.EditField_8Label.Text = ',';
            app.EditField_8 = uieditfield(app.ContrastReversingGratingTab, 'numeric');
            app.EditField_8.Limits = [0 1];
            app.TextArea_4Label = uilabel(app.ContrastReversingGratingTab);
            app.TextArea_4Label.Text = '';
            app.TextArea_4 = uitextarea(app.ContrastReversingGratingTab);
            app.TextArea_4.Value = {'Current Custom Locations'};
            app.AddCustomButton_4 = uibutton(app.ContrastReversingGratingTab, 'push');
            app.AddCustomButton_4.ButtonPushedFcn = createCallbackFcn(app, @AddCustomButton_4Pushed, true);
            app.AddCustomButton_4.Text = 'Add Custom';
            app.RemoveCustomButton_4 = uibutton(app.ContrastReversingGratingTab, 'push');
            app.RemoveCustomButton_4.ButtonPushedFcn = createCallbackFcn(app, @RemoveCustomButton_4Pushed, true);
            app.RemoveCustomButton_4.Text = 'Remove Custom';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.StopLabel = uilabel(app.UIFigure);
            app.StopLabel.Text = 'To stop current trial, hold down shift';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Gauge = uigauge(app.UIFigure, 'linear');
        end
    end

    methods (Access = public)

        % Construct app
        function app = PipelineGUI112219

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end