classdef trialRunner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NumberofTrialsTab               matlab.ui.container.Tab
        StartButton_2                   matlab.ui.control.Button
        VisualSettingsLabel             matlab.ui.control.Label
        NumberofTrialsWantedLabel_2     matlab.ui.control.Label
        soundtrialsEditField_2Label     matlab.ui.control.Label
        soundtrialsEditField_2          matlab.ui.control.NumericEditField
        visualtrialsEditField_2Label    matlab.ui.control.Label
        visualtrialsEditField_2         matlab.ui.control.NumericEditField
        bothtrialsEditField_2Label      matlab.ui.control.Label
        bothtrialsEditField_2           matlab.ui.control.NumericEditField
        angleEditField_2Label           matlab.ui.control.Label
        angleEditField_2                matlab.ui.control.NumericEditField
        cyclespersecondEditField_2Label  matlab.ui.control.Label
        cyclespersecondEditField_2      matlab.ui.control.NumericEditField
        cyclesperdegreeEditField_2Label  matlab.ui.control.Label
        cyclesperdegreeEditField_2      matlab.ui.control.NumericEditField
        gratingsizeEditField_2Label     matlab.ui.control.Label
        gratingsizeEditField_2          matlab.ui.control.NumericEditField
        samplingrateEditField_2Label    matlab.ui.control.Label
        samplingrateEditField_2         matlab.ui.control.NumericEditField
        tonefrequencyEditField_2Label   matlab.ui.control.Label
        tonefrequencyEditField_2        matlab.ui.control.NumericEditField
        signalfrequencyEditField_2Label  matlab.ui.control.Label
        signalfrequencyEditField_2      matlab.ui.control.NumericEditField
        durationEditField_2Label        matlab.ui.control.Label
        durationEditField_2             matlab.ui.control.NumericEditField
        masksizeEditField_2Label        matlab.ui.control.Label
        masksizeEditField_2             matlab.ui.control.NumericEditField
        AudioSettingsLabel              matlab.ui.control.Label
        maskonoffLabel                  matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        MultipleContrastRevGratingsTab  matlab.ui.container.Tab
        angleEditField_3Label           matlab.ui.control.Label
        angleEditField_3                matlab.ui.control.NumericEditField
        cyclesperdegreeEditField_3Label  matlab.ui.control.Label
        cyclesperdegreeEditField_3      matlab.ui.control.NumericEditField
        cyclespersecondEditField_3Label  matlab.ui.control.Label
        cyclespersecondEditField_3      matlab.ui.control.NumericEditField
        contrastEditFieldLabel          matlab.ui.control.Label
        contrastEditField               matlab.ui.control.NumericEditField
        distancetomonitorEditFieldLabel  matlab.ui.control.Label
        distancetomonitorEditField      matlab.ui.control.NumericEditField
        CheckBox_2                      matlab.ui.control.CheckBox
        maskonoffLabel_2                matlab.ui.control.Label
        gratingsizeEditField_3Label     matlab.ui.control.Label
        gratingsizeEditField_3          matlab.ui.control.NumericEditField
        StartButton_3                   matlab.ui.control.Button
        OddballTab                      matlab.ui.container.Tab
        durationEditField_3Label        matlab.ui.control.Label
        durationEditField_3             matlab.ui.control.NumericEditField
        SequencerLabel                  matlab.ui.control.Label
        startbufferEditFieldLabel       matlab.ui.control.Label
        startbufferEditField            matlab.ui.control.NumericEditField
        betweenbufferEditFieldLabel     matlab.ui.control.Label
        betweenbufferEditField          matlab.ui.control.NumericEditField
        SequenceButton                  matlab.ui.control.Button
        stimulusvectorEditField_2Label  matlab.ui.control.Label
        stimulusvectorEditField_2       matlab.ui.control.EditField
        delayEditFieldLabel             matlab.ui.control.Label
        delayEditField                  matlab.ui.control.NumericEditField
        PlayerLabel                     matlab.ui.control.Label
        PlayButton                      matlab.ui.control.Button
        GeneratedSequenceEditFieldLabel  matlab.ui.control.Label
        GeneratedSequenceEditField      matlab.ui.control.EditField
        FrequenciesEditFieldLabel       matlab.ui.control.Label
        FrequenciesEditField            matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            seqNum = str2num(app.GeneratedSequenceEditField.Value);
            freqNum = str2num(app.FrequenciesEditField.Value);
            oddballPlayer(seqNum, freqNum, app.durationEditField_3.Value,...
                app.delayEditField.Value);
        end

        % Button pushed function: SequenceButton
        function SequenceButtonPushed(app, event)
            num = str2num(app.stimulusvectorEditField_2.Value);
            app.GeneratedSequenceEditField.Value = mat2str(oddballSequencer(num,...
                app.startbufferEditField.Value, app.betweenbufferEditField.Value));
        end

        % Button pushed function: StartButton_2
        function StartButtonPushed(app, event)
             numberoftrials(app.soundtrialsEditField_2.Value,...
                 app.visualtrialsEditField_2.Value, app.bothtrialsEditField_2.Value,...
                 app.angleEditField_2.Value, app.cyclespersecondEditField_2.Value,...
                 app.cyclesperdegreeEditField_2.Value, app.CheckBox.Value,...
                 app.masksizeEditField_2.Value, app.gratingsizeEditField_2.Value,...
                 app.samplingrateEditField_2.Value, app.tonefrequencyEditField_2.Value,...
                 app.signalfrequencyEditField_2.Value, app.durationEditField_2.Value)
        end

        % Callback function
        function StartButton_2Pushed(app, event)
             numberoftrials(app.soundtrialsEditField_2.Value,...
                 app.visualtrialsEditField_2.Value, app.bothtrialsEditField_2.Value,...
                 app.angleEditField_2.Value, app.cyclespersecondEditField_2.Value,...
                 app.cyclesperdegreeEditField_2.Value, app.CheckBox.Value,...
                 app.masksizeEditField_2.Value, app.gratingsizeEditField_2.Value,...
                 app.samplingrateEditField_2.Value, app.tonefrequencyEditField_2.Value,...
                 app.signalfrequencyEditField_2.Value, app.durationEditField_2.Value)
        end

        % Button pushed function: StartButton_3
        function StartButton_3Pushed(app, event)
            dispGratings(app.angleEditField_3.Value, app.cyclesperdegreeEditField_3.Value,...
                app.cyclespersecondEditField_3.Value, app.contrastEditField.Value,...
                app.distancetomonitorEditField.Value, app.gratingsizeEditField_3.Value,...
                app.CheckBox_2.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NumberofTrialsTab = uitab(app.TabGroup);
            app.NumberofTrialsTab.Title = 'NumberofTrials';
            app.StartButton_2 = uibutton(app.NumberofTrialsTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton_2.Text = 'Start';
            app.VisualSettingsLabel = uilabel(app.NumberofTrialsTab);
            app.VisualSettingsLabel.Text = 'Visual Settings';
            app.NumberofTrialsWantedLabel_2 = uilabel(app.NumberofTrialsTab);
            app.NumberofTrialsWantedLabel_2.Text = 'Number of Trials Wanted';
            app.soundtrialsEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.soundtrialsEditField_2Label.Text = 'sound trials';
            app.soundtrialsEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.soundtrialsEditField_2.Value = 1;
            app.visualtrialsEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.visualtrialsEditField_2Label.Text = 'visual trials';
            app.visualtrialsEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.visualtrialsEditField_2.Value = 1;
            app.bothtrialsEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.bothtrialsEditField_2Label.Text = 'both trials';
            app.bothtrialsEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.bothtrialsEditField_2.Value = 1;
            app.angleEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.angleEditField_2Label.Text = 'angle';
            app.angleEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.angleEditField_2.Limits = [0 Inf];
            app.angleEditField_2.Value = 30;
            app.cyclespersecondEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.cyclespersecondEditField_2Label.Text = 'cyclespersecond';
            app.cyclespersecondEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.cyclespersecondEditField_2.Limits = [0 Inf];
            app.cyclespersecondEditField_2.Value = 1;
            app.cyclesperdegreeEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.cyclesperdegreeEditField_2Label.Text = 'cyclesperdegree';
            app.cyclesperdegreeEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.cyclesperdegreeEditField_2.Limits = [0 Inf];
            app.cyclesperdegreeEditField_2.Value = 0.4;
            app.gratingsizeEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.gratingsizeEditField_2Label.Text = 'grating size';
            app.gratingsizeEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.gratingsizeEditField_2.Limits = [0 Inf];
            app.gratingsizeEditField_2.Value = 400;
            app.samplingrateEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.samplingrateEditField_2Label.Text = 'sampling rate';
            app.samplingrateEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.samplingrateEditField_2.Limits = [0 Inf];
            app.samplingrateEditField_2.Value = 44100;
            app.tonefrequencyEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.tonefrequencyEditField_2Label.Text = 'tone frequency';
            app.tonefrequencyEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.tonefrequencyEditField_2.Limits = [0 Inf];
            app.tonefrequencyEditField_2.Value = 440;
            app.signalfrequencyEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.signalfrequencyEditField_2Label.Text = 'signal frequency';
            app.signalfrequencyEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.signalfrequencyEditField_2.Limits = [0 Inf];
            app.signalfrequencyEditField_2.Value = 0.5;
            app.durationEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.durationEditField_2Label.Text = 'duration';
            app.durationEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.durationEditField_2.Limits = [0 Inf];
            app.durationEditField_2.Value = 10;
            app.masksizeEditField_2Label = uilabel(app.NumberofTrialsTab);
            app.masksizeEditField_2Label.Text = 'mask size';
            app.masksizeEditField_2 = uieditfield(app.NumberofTrialsTab, 'numeric');
            app.masksizeEditField_2.Limits = [0 Inf];
            app.masksizeEditField_2.Value = 90;
            app.AudioSettingsLabel = uilabel(app.NumberofTrialsTab);
            app.AudioSettingsLabel.Text = 'Audio Settings';
            app.maskonoffLabel = uilabel(app.NumberofTrialsTab);
            app.maskonoffLabel.Text = 'mask on/off';
            app.CheckBox = uicheckbox(app.NumberofTrialsTab);
            app.CheckBox.Value = true;
            app.MultipleContrastRevGratingsTab = uitab(app.TabGroup);
            app.MultipleContrastRevGratingsTab.Title = 'MultipleContrastRevGratings';
            app.angleEditField_3Label = uilabel(app.MultipleContrastRevGratingsTab);
            app.angleEditField_3Label.Text = 'angle';
            app.angleEditField_3 = uieditfield(app.MultipleContrastRevGratingsTab, 'numeric');
            app.angleEditField_3.Limits = [0 180];
            app.angleEditField_3.Value = 90;
            app.cyclesperdegreeEditField_3Label = uilabel(app.MultipleContrastRevGratingsTab);
            app.cyclesperdegreeEditField_3Label.Text = 'cycles per degree';
            app.cyclesperdegreeEditField_3 = uieditfield(app.MultipleContrastRevGratingsTab, 'numeric');
            app.cyclesperdegreeEditField_3.Limits = [0 Inf];
            app.cyclesperdegreeEditField_3.Value = 0.1;
            app.cyclespersecondEditField_3Label = uilabel(app.MultipleContrastRevGratingsTab);
            app.cyclespersecondEditField_3Label.Text = 'cycles per second';
            app.cyclespersecondEditField_3 = uieditfield(app.MultipleContrastRevGratingsTab, 'numeric');
            app.cyclespersecondEditField_3.Limits = [0 Inf];
            app.cyclespersecondEditField_3.Value = 1;
            app.contrastEditFieldLabel = uilabel(app.MultipleContrastRevGratingsTab);
            app.contrastEditFieldLabel.Text = 'contrast';
            app.contrastEditField = uieditfield(app.MultipleContrastRevGratingsTab, 'numeric');
            app.contrastEditField.Limits = [0 1];
            app.contrastEditField.Value = 1;
            app.distancetomonitorEditFieldLabel = uilabel(app.MultipleContrastRevGratingsTab);
            app.distancetomonitorEditFieldLabel.Text = 'distance to monitor';
            app.distancetomonitorEditField = uieditfield(app.MultipleContrastRevGratingsTab, 'numeric');
            app.distancetomonitorEditField.Limits = [0 Inf];
            app.distancetomonitorEditField.Value = 25;
            app.CheckBox_2 = uicheckbox(app.MultipleContrastRevGratingsTab);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.maskonoffLabel_2 = uilabel(app.MultipleContrastRevGratingsTab);
            app.maskonoffLabel_2.Text = 'mask on/off';
            app.gratingsizeEditField_3Label = uilabel(app.MultipleContrastRevGratingsTab);
            app.gratingsizeEditField_3Label.Text = 'grating size';
            app.gratingsizeEditField_3 = uieditfield(app.MultipleContrastRevGratingsTab, 'numeric');
            app.gratingsizeEditField_3.Limits = [0 Inf];
            app.gratingsizeEditField_3.Value = 5;
            app.StartButton_3 = uibutton(app.MultipleContrastRevGratingsTab, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButton_3Pushed, true);
            app.StartButton_3.Text = 'Start!';
            app.OddballTab = uitab(app.TabGroup);
            app.OddballTab.Title = 'Oddball';
            app.durationEditField_3Label = uilabel(app.OddballTab);
            app.durationEditField_3Label.Text = 'duration';
            app.durationEditField_3 = uieditfield(app.OddballTab, 'numeric');
            app.durationEditField_3.Limits = [0 Inf];
            app.durationEditField_3.Value = 0.5;
            app.SequencerLabel = uilabel(app.OddballTab);
            app.SequencerLabel.Text = 'Sequencer';
            app.startbufferEditFieldLabel = uilabel(app.OddballTab);
            app.startbufferEditFieldLabel.Text = 'start buffer';
            app.startbufferEditField = uieditfield(app.OddballTab, 'numeric');
            app.startbufferEditField.Limits = [0 Inf];
            app.startbufferEditField.Value = 3;
            app.betweenbufferEditFieldLabel = uilabel(app.OddballTab);
            app.betweenbufferEditFieldLabel.Text = 'between buffer';
            app.betweenbufferEditField = uieditfield(app.OddballTab, 'numeric');
            app.betweenbufferEditField.Limits = [0 Inf];
            app.betweenbufferEditField.Value = 3;
            app.SequenceButton = uibutton(app.OddballTab, 'push');
            app.SequenceButton.ButtonPushedFcn = createCallbackFcn(app, @SequenceButtonPushed, true);
            app.SequenceButton.Text = 'Sequence';
            app.stimulusvectorEditField_2Label = uilabel(app.OddballTab);
            app.stimulusvectorEditField_2Label.Text = 'stimulus vector';
            app.stimulusvectorEditField_2 = uieditfield(app.OddballTab, 'text');
            app.stimulusvectorEditField_2.Value = '[14, 1, 1]';
            app.delayEditFieldLabel = uilabel(app.OddballTab);
            app.delayEditFieldLabel.Text = 'delay';
            app.delayEditField = uieditfield(app.OddballTab, 'numeric');
            app.delayEditField.Limits = [0 Inf];
            app.delayEditField.Value = 0.5;
            app.PlayerLabel = uilabel(app.OddballTab);
            app.PlayerLabel.Text = 'Player';
            app.PlayButton = uibutton(app.OddballTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.GeneratedSequenceEditFieldLabel = uilabel(app.OddballTab);
            app.GeneratedSequenceEditFieldLabel.Text = 'Generated Sequence';
            app.GeneratedSequenceEditField = uieditfield(app.OddballTab, 'text');
            app.FrequenciesEditFieldLabel = uilabel(app.OddballTab);
            app.FrequenciesEditFieldLabel.Text = 'Frequencies';
            app.FrequenciesEditField = uieditfield(app.OddballTab, 'text');
            app.FrequenciesEditField.Value = '[400,300,500]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = trialRunner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end